classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        NewtonsCalculatorLabel  matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        ForceNEditField         matlab.ui.control.NumericEditField
        ForceNEditFieldLabel    matlab.ui.control.Label
        Accms2EditField         matlab.ui.control.NumericEditField
        Accms2EditFieldLabel    matlab.ui.control.Label
        MassKgEditField         matlab.ui.control.NumericEditField
        MassKgEditFieldLabel    matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        CalculateButton         matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
            mass=app.MassKgEditField.Value;
            acc=app.Accms2EditField.Value;
            
            %Newton's Second Law F = ma
            force = mass*acc;

            app.ForceNEditField.Value = force;

            if force >= 0 && force <=500 
                app.Lamp.Color = 'g';
            elseif force > 500 && force <= 1000
                app.Lamp.Color = [0.98,0.69,0.02];
            else 
                app.Lamp.Color = 'red';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.CalculateButton = uibutton(app.GridLayout, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Calculate';
            app.Lamp = uilamp(app.GridLayout);
            app.MassKgEditFieldLabel = uilabel(app.GridLayout);
            app.MassKgEditFieldLabel.Text = 'Mass (Kg)';
            app.MassKgEditField = uieditfield(app.GridLayout, 'numeric');
            app.Accms2EditFieldLabel = uilabel(app.GridLayout);
            app.Accms2EditFieldLabel.Text = 'Acc (m/s^2)';
            app.Accms2EditField = uieditfield(app.GridLayout, 'numeric');
            app.ForceNEditFieldLabel = uilabel(app.GridLayout);
            app.ForceNEditFieldLabel.Text = 'Force (N)';
            app.ForceNEditField = uieditfield(app.GridLayout, 'numeric');
            app.NewtonsCalculatorLabel = uilabel(app.UIFigure);
            app.NewtonsCalculatorLabel.Text = 'Newton''s Calculator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end