classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SensorInputEditField       matlab.ui.control.NumericEditField
        SensorInputEditFieldLabel  matlab.ui.control.Label
        NotActiveLabel             matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function SensorInputEditFieldValueChanged(app, event)

        end

        % Value changed function: SensorInputEditField
        function SensorInputEditFieldValueChanged2(app, event)
            value = app.SensorInputEditField.Value;
            switch value
                case 0
                    app.Lamp.Color = [0.90,0.90,0.90]; %Grey
                    app.NotActiveLabel.Text = 'Not Active';
                case 1
                    app.Lamp.Color = [0 1 0]; %Green
                    app.NotActiveLabel.Text = 'Active';
                case 2
                    app.Lamp.Color = [1 0 0]; %Red
                    app.NotActiveLabel.Text = 'Error!';
                otherwise
                    app.Lamp.Color = [0 0 0]; %Black
                    app.NotActiveLabel.Text = 'State Unknown!';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Lamp = uilamp(app.UIFigure);
            app.NotActiveLabel = uilabel(app.UIFigure);
            app.NotActiveLabel.Text = 'Not Active';
            app.SensorInputEditFieldLabel = uilabel(app.UIFigure);
            app.SensorInputEditFieldLabel.Text = 'Sensor Input';
            app.SensorInputEditField = uieditfield(app.UIFigure, 'numeric');
            app.SensorInputEditField.ValueChangedFcn = createCallbackFcn(app, @SensorInputEditFieldValueChanged2, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end