classdef app8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ReadDataButton  matlab.ui.control.Button
        UITable         matlab.ui.control.Table
        UIAxes          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % All functions
        function sampleReadData(app)
            table = readtable ('sample data.xlsx','Sheet','Sheet1');
            app.UITable.Data = table;
            table.Properties.VariableNames{1} = 'x Data';
            table.Properties.VariableNames{2} = 'y Data';
            app.UITable.ColumnName = table.Properties.VariableNames;
        end

        function samplePlotData(app)
            table = readtable ('sample data.xlsx','Sheet','Sheet1');
            x= table2array(table(:,'x'));
            y= table2array(table(:,'y'));
            plot(app.UIAxes,x,y);
        end

        function sampleChangeCellData(app)
            table = readtable ('sample data.xlsx','Sheet','Sheet1');
            table.x(1)=99;
            table.y(1)=99;
            app.UITable.Data = table;
            table.Properties.VariableNames{1} = 'x Data';
            table.Properties.VariableNames{2} = 'y Data';
            app.UITable.ColumnName = table.Properties.VariableNames;
        end

        function sampleAddRowData(app)
            table = readtable ('sample data.xlsx','Sheet','Sheet1');
            app.UITable.Data = table;
            nr = {table;10};
            table.Properties.VariableNames{1} = 'x Data';
            table.Properties.VariableNames{2} = 'y Data';
            app.UITable.ColumnName = table.Properties.VariableNames;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            samplePlotData(app);
            sampleReadData(app);
            sampleChangeCellData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'y=x^2')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.ReadDataButton = uibutton(app.UIFigure, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'Read Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end