classdef app9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AddtotableButton    matlab.ui.control.Button
        AgeEditField        matlab.ui.control.NumericEditField
        AgeEditFieldLabel   matlab.ui.control.Label
        NameEditField       matlab.ui.control.EditField
        NameEditFieldLabel  matlab.ui.control.Label
        ReadxlsxButton      matlab.ui.control.Button
        UITable             matlab.ui.control.Table
    end

    
    properties (Access = private)
        table % table data to share
    end
    
    methods (Access = private)
        
        % All functions
        function sampleReadData(app)
            app.table = readtable ('sample data.xlsx','Sheet','Sheet2');
            app.UITable.Data = app.table;
            app.UITable.ColumnName = app.table.Properties.VariableNames;
        end

        function sampleAddRow(app)
            name = app.NameEditField.Value;
            age = app.AgeEditField.Value;
            nr = {name age};
            app.UITable.Data = [app.table;nr];
            app.table = app.UITable.Data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadxlsxButton
        function ReadxlsxButtonPushed(app, event)
            sampleReadData(app);
        end

        % Button pushed function: AddtotableButton
        function AddtotableButtonPushed(app, event)
            sampleAddRow(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ReadxlsxButton = uibutton(app.UIFigure, 'push');
            app.ReadxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @ReadxlsxButtonPushed, true);
            app.ReadxlsxButton.Text = 'Read .xlsx';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.AgeEditFieldLabel = uilabel(app.UIFigure);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddtotableButton = uibutton(app.UIFigure, 'push');
            app.AddtotableButton.ButtonPushedFcn = createCallbackFcn(app, @AddtotableButtonPushed, true);
            app.AddtotableButton.Text = 'Add to table';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end