classdef bus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BusUIFigure                  matlab.ui.Figure
        title                        matlab.ui.control.Label
        ButtonGroup                  matlab.ui.container.ButtonGroup
        SaveButton                   matlab.ui.control.Button
        ql_bus_EditField             matlab.ui.control.NumericEditField
        ql_unit_bus_EditField        matlab.ui.control.EditField
        pl_bus_EditField             matlab.ui.control.NumericEditField
        pl_unit_bus_EditField        matlab.ui.control.EditField
        f_bus_EditField              matlab.ui.control.NumericEditField
        f_unit_bus_EditField         matlab.ui.control.EditField
        l_bus_EditField              matlab.ui.control.NumericEditField
        l_unit_bus_EditField         matlab.ui.control.EditField
        i_bus_EditField              matlab.ui.control.NumericEditField
        i_unit_bus_EditField         matlab.ui.control.EditField
        v_bus_rating_EditField       matlab.ui.control.NumericEditField
        v_unit_bus_rating_EditField  matlab.ui.control.EditField
        qg_bus_EditField             matlab.ui.control.NumericEditField
        qg_unit_bus_EditField        matlab.ui.control.EditField
        pg_bus_EditField             matlab.ui.control.NumericEditField
        pg_unit_bus_EditField        matlab.ui.control.EditField
    end

    
    properties (Access = private)
        callingapp % Description
        bus_id;
        bus_state;
        v_bus_rating;
        pg_bus;
        pl_bus;
        qg_bus;
        ql_bus;
        l_bus;
        i_bus;
        s_bus;
        f_bus;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, guisimulation, bus_id, bus_state, v_rating, pg, pl, qg, ql, l, i, f)
            app.callingapp = guisimulation;
            app.bus_id = num2str(bus_id);
            app.title.Text = strcat(app.title.Text,app.bus_id);

            %variable
            app.v_bus_rating = v_rating;
            app.pg_bus = pg;
            app.pl_bus = pl;
            app.qg_bus = qg;
            app.ql_bus = ql;
            app.l_bus = l;
            app.i_bus = i;
            app.f_bus = f;

            %display value
            app.v_bus_rating_EditField.Value = v_rating;
            app.pg_bus_EditField.Value = pg;
            app.pl_bus_EditField.Value = pl;
            app.i_bus_EditField.Value = i;
            app.l_bus_EditField.Value = l;
            app.f_bus_EditField.Value = f;
            app.qg_bus_EditField.Value = qg;
            app.ql_bus_EditField.Value = ql;
        end

        % Key press function: BusUIFigure
        function BusUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'escape')
                delete(app);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            v_rating = app.v_bus_rating_EditField.Value;
            pg = app.pg_bus_EditField.Value;
            pl = app.pl_bus_EditField.Value;
            qg = app.qg_bus_EditField.Value;
            ql = app.ql_bus_EditField.Value;
            l = app.l_bus_EditField.Value;
            i = app.i_bus_EditField.Value;
            f = app.f_bus_EditField.Value;
            if strcmp(app.bus_id,'101-HSW-A02')
                importdata_bus_a02(app.callingapp,v_rating,pg,pl,qg,ql, l, i, f)
            elseif strcmp(app.bus_id,'101-HSW-A03')
                importdata_bus_a03(app.callingapp,v_rating,pg,pl,qg,ql, l, i, f)
            elseif strcmp(app.bus_id,'101-HSW-C01A')
                importdata_bus_c01a(app.callingapp,v_rating,pg,pl,qg,ql, l, i, f)
            elseif strcmp(app.bus_id,'101-HSW-C01B')
                importdata_bus_c01b(app.callingapp,v_rating,pg,pl,qg,ql, l, i, f) 
            else

            end

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BusUIFigure = uifigure('Visible', 'off');
            app.BusUIFigure.Name = 'Bus';
            app.BusUIFigure.KeyPressFcn = createCallbackFcn(app, @BusUIFigureKeyPress, true);
            app.ButtonGroup = uibuttongroup(app.BusUIFigure);
            app.pg_unit_bus_EditField = uieditfield(app.ButtonGroup, 'text');
            app.pg_unit_bus_EditField.Value = 'kW Generated';
            app.pg_bus_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.qg_unit_bus_EditField = uieditfield(app.ButtonGroup, 'text');
            app.qg_unit_bus_EditField.Value = 'kVar Generated';
            app.qg_bus_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.v_unit_bus_rating_EditField = uieditfield(app.ButtonGroup, 'text');
            app.v_unit_bus_rating_EditField.Value = 'kV';
            app.v_bus_rating_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.i_unit_bus_EditField = uieditfield(app.ButtonGroup, 'text');
            app.i_unit_bus_EditField.Value = 'A';
            app.i_bus_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.l_unit_bus_EditField = uieditfield(app.ButtonGroup, 'text');
            app.l_unit_bus_EditField.Value = '%';
            app.l_bus_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.f_unit_bus_EditField = uieditfield(app.ButtonGroup, 'text');
            app.f_unit_bus_EditField.Value = 'Hz';
            app.f_bus_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.pl_unit_bus_EditField = uieditfield(app.ButtonGroup, 'text');
            app.pl_unit_bus_EditField.Value = 'kW Load';
            app.pl_bus_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.ql_unit_bus_EditField = uieditfield(app.ButtonGroup, 'text');
            app.ql_unit_bus_EditField.Value = 'kVar Load';
            app.ql_bus_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.SaveButton = uibutton(app.ButtonGroup, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.title = uilabel(app.BusUIFigure);
            app.title.Text = 'BUS UNIT # ';
            % Show the figure after all components are created
            app.BusUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bus(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BusUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BusUIFigure)
        end
    end
end