classdef generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeneratorUIFigure            matlab.ui.Figure
        title                        matlab.ui.control.Label
        ButtonGroup                  matlab.ui.container.ButtonGroup
        SaveButton                   matlab.ui.control.Button
        f_gen_rating_EditField       matlab.ui.control.NumericEditField
        f_unit_gen_rating_EditField  matlab.ui.control.EditField
        p_gen_EditField              matlab.ui.control.NumericEditField
        p_unit_gen_EditField         matlab.ui.control.EditField
        l_gen_EditField              matlab.ui.control.NumericEditField
        l_unit_gen_EditField         matlab.ui.control.EditField
        i_gen_EditField              matlab.ui.control.NumericEditField
        i_unit_gen_EditField         matlab.ui.control.EditField
        v_gen_rating_EditField       matlab.ui.control.NumericEditField
        v_unit_gen_rating_EditField  matlab.ui.control.EditField
        q_gen_EditField              matlab.ui.control.NumericEditField
        q_unit_gen_EditField         matlab.ui.control.EditField
        s_gen_rating_EditField       matlab.ui.control.NumericEditField
        s_unit_gen_rating_EditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        callingapp ; % Description
        generator_id;
        generator_state;
        s_gen_rating;
        v_gen_rating;
        f_gen_rating;
        l_gen;
        i_gen;
        p_gen;
        q_gen;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, psathmi, generator_id, generator_state, s_rating, v_rating, f_rating, l, i, p, q)
            app.callingapp = psathmi;
            app.generator_id = num2str(generator_id);
            app.title.Text = strcat(app.title.Text,app.generator_id);

            %variable
            app.s_gen_rating = s_rating;
            app.v_gen_rating = v_rating;
            app.f_gen_rating = f_rating;
            app.l_gen = l;
            app.i_gen = i;
            app.p_gen = p;
            app.q_gen = q;

            %display value
            app.s_gen_rating_EditField.Value = s_rating;
            app.v_gen_rating_EditField.Value = v_rating;
            app.f_gen_rating_EditField.Value = f_rating;
            app.i_gen_EditField.Value = i;
            app.l_gen_EditField.Value = l;
            app.p_gen_EditField.Value = p;
            app.q_gen_EditField.Value = q;
        end

        % Key press function: GeneratorUIFigure
        function GeneratorUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'escape')
                delete(app);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            s_rating = app.s_gen_rating_EditField.Value;
            v_rating = app.v_gen_rating_EditField.Value;
            f_rating = app.f_gen_rating_EditField.Value;
            i = app.i_gen_EditField.Value;
            l = app.l_gen_EditField.Value;
            p = app.p_gen_EditField.Value;
            q = app.q_gen_EditField.Value;
            if strcmp(app.generator_id,'SG01')
                importdata_gen_sg01(app.callingapp,s_rating, v_rating, f_rating, l, i, p, q)
            elseif strcmp(app.generator_id,'SG02')
                importdata_gen_sg02(app.callingapp,s_rating, v_rating, f_rating, l, i, p, q)
            elseif strcmp(app.generator_id,'SG03')
                importdata_gen_sg03(app.callingapp,s_rating, v_rating, f_rating, l, i, p, q)
            else
                importdata_gen_gt01(app.callingapp,s_rating, v_rating, f_rating, l, i, p, q)
            end
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeneratorUIFigure = uifigure('Visible', 'off');
            app.GeneratorUIFigure.Name = 'Generator';
            app.GeneratorUIFigure.KeyPressFcn = createCallbackFcn(app, @GeneratorUIFigureKeyPress, true);
            app.ButtonGroup = uibuttongroup(app.GeneratorUIFigure);
            app.s_unit_gen_rating_EditField = uieditfield(app.ButtonGroup, 'text');
            app.s_unit_gen_rating_EditField.Value = 'kVa';
            app.s_gen_rating_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.q_unit_gen_EditField = uieditfield(app.ButtonGroup, 'text');
            app.q_unit_gen_EditField.Value = 'kVar';
            app.q_gen_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.v_unit_gen_rating_EditField = uieditfield(app.ButtonGroup, 'text');
            app.v_unit_gen_rating_EditField.Value = 'kV';
            app.v_gen_rating_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.i_unit_gen_EditField = uieditfield(app.ButtonGroup, 'text');
            app.i_unit_gen_EditField.Value = 'A';
            app.i_gen_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.l_unit_gen_EditField = uieditfield(app.ButtonGroup, 'text');
            app.l_unit_gen_EditField.Value = '%';
            app.l_gen_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.p_unit_gen_EditField = uieditfield(app.ButtonGroup, 'text');
            app.p_unit_gen_EditField.Value = 'kW';
            app.p_gen_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.f_unit_gen_rating_EditField = uieditfield(app.ButtonGroup, 'text');
            app.f_unit_gen_rating_EditField.Value = 'Hz';
            app.f_gen_rating_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.SaveButton = uibutton(app.ButtonGroup, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.title = uilabel(app.GeneratorUIFigure);
            app.title.Text = 'GENERATOR UNIT # ';
            % Show the figure after all components are created
            app.GeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = generator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeneratorUIFigure)
        end
    end
end