classdef guisimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PSATSimulationUIFigure  matlab.ui.Figure
        trafo_2_img             matlab.ui.control.Image
        trafo_1_img             matlab.ui.control.Image
        load_gen_4_EditField    matlab.ui.control.NumericEditField
        LLabel_2                matlab.ui.control.Label
        load_gen_4_unit         matlab.ui.control.EditField
        load_gen_3_EditField    matlab.ui.control.NumericEditField
        LLabel                  matlab.ui.control.Label
        load_gen_3_unit         matlab.ui.control.EditField
        load_gen_2_EditField    matlab.ui.control.NumericEditField
        LoadLabel_2             matlab.ui.control.Label
        load_gen_2_unit         matlab.ui.control.EditField
        load_gen_1_EditField    matlab.ui.control.NumericEditField
        LoadLabel               matlab.ui.control.Label
        load_gen_1_unit         matlab.ui.control.EditField
        OPCDataButton           matlab.ui.control.Button
        StartPowerFlowButton    matlab.ui.control.Button
        SimulationOnLineButton  matlab.ui.control.Button
        ForceConditionButton    matlab.ui.control.Button
        motor_2_img             matlab.ui.control.Image
        motor_1_img             matlab.ui.control.Image
        breaker_4_label         matlab.ui.control.Label
        unit_motor_2_EditField  matlab.ui.control.EditField
        PEditFieldLabel_6       matlab.ui.control.Label
        P_motor_2_EditField     matlab.ui.control.NumericEditField
        unit_motor_1_EditField  matlab.ui.control.EditField
        P_motor_1_EditField     matlab.ui.control.NumericEditField
        PEditFieldLabel_5       matlab.ui.control.Label
        unit_gen_1_EditField_4  matlab.ui.control.EditField
        P_gen_4_EditField       matlab.ui.control.NumericEditField
        PEditFieldLabel_4       matlab.ui.control.Label
        unit_gen_3_EditField    matlab.ui.control.EditField
        P_gen_3_EditField       matlab.ui.control.NumericEditField
        PEditFieldLabel_3       matlab.ui.control.Label
        unit_gen_2_EditField    matlab.ui.control.EditField
        P_gen_2_EditField       matlab.ui.control.NumericEditField
        PEditFieldLabel_2       matlab.ui.control.Label
        unit_gen_1_EditField    matlab.ui.control.EditField
        P_gen_1_EditField       matlab.ui.control.NumericEditField
        PEditFieldLabel         matlab.ui.control.Label
        simulation_state_label  matlab.ui.control.Label
        breaker_2_label         matlab.ui.control.Label
        breaker_1_label         matlab.ui.control.Label
        breaker_3_label         matlab.ui.control.Label
        breaker_3_img           matlab.ui.control.Image
        breaker_4_img           matlab.ui.control.Image
        breaker_2_img           matlab.ui.control.Image
        breaker_1_img           matlab.ui.control.Image
        gen_4_img               matlab.ui.control.Image
        gen_3_img               matlab.ui.control.Image
        gen_2_img               matlab.ui.control.Image
        gen_1_img               matlab.ui.control.Image
        trafo_1_label           matlab.ui.control.Label
        trafo_2_label           matlab.ui.control.Label
        condition_3_label       matlab.ui.control.Label
        condition_2_label       matlab.ui.control.Label
        condition_1_label       matlab.ui.control.Label
        control_panel           matlab.ui.container.Panel
        gen_3_button            matlab.ui.control.Button
        gen_4_button            matlab.ui.control.Button
        gen_2_button            matlab.ui.control.Button
        gen_1_button            matlab.ui.control.Button
        breaker_4_button        matlab.ui.control.Button
        breaker_3_button        matlab.ui.control.Button
        breaker_2_button        matlab.ui.control.Button
        breaker_1_button        matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        ContextMenu             matlab.ui.container.ContextMenu
        SettingsMenu            matlab.ui.container.Menu
        CloseMenu               matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %state
        Gen_1_status = 0
        Gen_2_status = 0
        Gen_3_status = 0
        Gen_4_status = 0
        Breaker_1_status = 0
        Breaker_2_status = 0
        Breaker_3_status = 0
        Breaker_4_status = 0
        Motor_1_status = 0
        Motor_2_status=  0
        condition_1 = 0;
        condition_2 = 0;
        condition_3 = 0;
        all_condition;
        sim_state =0;
        
        %variable
        p_gen_1 = 10;
        l_gen_1 = 0;
        v_gen_1 = 11.0;
        i_gen_1 = 50;
        p_motor_1 = 20;
        p_gen_2 = 10;

        %instance
        requirement;
        generator;
        motor;
        transformer;
        opc;
    end
    
    methods (Access = private)
        function all_condition_check(app)
            if (app.condition_1 == 1 && app.condition_2 == 1 && app.condition_3 == 1)
                app.all_condition =1;
            else
                app.all_condition =0;
            end
        end

        function change_status_gen_1(app)
            if(app.Gen_1_status == 1)
                app.Gen_1_status = 0;

            else
                app.Gen_1_status = 1;
            end
            
        end

        function change_color_gen_1(app)
            if(app.Gen_1_status == 1)
                app.gen_1_img.BackgroundColor = 'r';
                
            else
                app.gen_1_img.BackgroundColor = 'g';
                
            end
        end

        function change_status_gen_2(app)
            if(app.Gen_2_status == 1)
                app.Gen_2_status = 0;
            else
                app.Gen_2_status = 1;
            end
           
        end

        function change_color_gen_2(app)
            if(app.Gen_2_status == 1)
                app.gen_2_img.BackgroundColor = 'r';
            else
                app.gen_2_img.BackgroundColor = 'g';
            end
           
        end
        
        function change_status_gen_3(app)
            if(app.Gen_3_status == 1)
                app.Gen_3_status = 0;
            else
                app.Gen_3_status = 1;
            end
            app.P_gen_3_EditField.Value = app.Gen_3_status;
        end

        function change_color_gen_3(app)
            if(app.Gen_3_status == 1)
                app.gen_3_img.BackgroundColor = 'r';               
            else
                app.gen_3_img.BackgroundColor = 'g';
            end
        end
 
        function change_status_gen_4(app)
            if(app.Gen_4_status == 1)
                app.Gen_4_status = 0;
            else
                app.Gen_4_status = 1;
            end
            app.P_gen_4_EditField.Value = app.Gen_4_status;
        end

        function change_color_gen_4(app)
            if(app.Gen_4_status == 1)
                app.gen_4_img.BackgroundColor = 'r';
            else
                app.gen_4_img.BackgroundColor = 'g';
            end
        end
        
        function change_status_break_1(app)
            if(app.Breaker_1_status == 1)
                app.Breaker_1_status = 0;
                app.breaker_1_img.BackgroundColor = 'r';
                
            else
                app.Breaker_1_status = 1;
                app.breaker_1_img.BackgroundColor = 'g';
                
            end
        end

        function change_color_break_1(app)
            if(app.Breaker_1_status == 1)
                app.breaker_1_img.BackgroundColor = 'r';
                
            else
                app.breaker_1_img.BackgroundColor = 'g';
                
            end
        end
        
        function change_status_break_2(app)
            if(app.Breaker_2_status == 1)
                app.Breaker_2_status = 0;
            else
                app.Breaker_2_status = 1;
            end
        end

        function change_color_break_2(app)
            if(app.Breaker_2_status == 1)
                app.breaker_2_img.BackgroundColor = 'r';
            else
                app.breaker_2_img.BackgroundColor = 'g';
            end
        end

        function change_status_break_3(app)
            if(app.Breaker_3_status == 1)
                app.Breaker_3_status = 0;
            else
                app.Breaker_3_status = 1;
            end
        end

        function change_color_break_3(app)
            if(app.Breaker_3_status == 1)
                app.breaker_3_img.BackgroundColor = 'r';
            else
                app.breaker_3_img.BackgroundColor = 'g';
            end
        end

        function change_status_break_4(app)
            if(app.Breaker_4_status == 1)
                app.Breaker_4_status = 0;
            else
                app.Breaker_4_status = 1;
            end
        end

        function change_color_break_4(app)
            if(app.Breaker_4_status == 1)
                app.breaker_4_img.BackgroundColor = 'r';
            else
                app.breaker_4_img.BackgroundColor = 'g';
            end
        end
        
        %{
        function motor_state(app,motor_number,state)
            if motor_number == 1
                if state == 1
                    app.Motor_1_status=1;
                    app.motor_1_img.BackgroundColor = 'r';
                else
                    app.Motor_1_status=0;
                    app.motor_1_img.BackgroundColor = 'g';
                end
            else
                if state == 1
                    app.Motor_2_status=1;
                    app.motor_2_img.BackgroundColor = 'r';
                else
                    app.Motor_2_status=0;
                    app.motor_2_img.BackgroundColor = 'g';
                end
            end
        end
        %}

        %{
        function gen_state(app,gen_number,state)
            if gen_number == 1
                if state == 1
                    app.Gen_1_status=1;
                    app.gen_1_img.BackgroundColor = 'r';
                else
                    app.Gen_1_status=0;
                    app.gen_1_img.BackgroundColor = 'g';
                end
            elseif gen_number == 2
                if state == 1
                    app.Gen_2_status=1;
                    app.gen_2_img.BackgroundColor = 'r';
                else
                    app.Gen_2_status=0;
                    app.gen_2_img.BackgroundColor = 'g';
                end
            elseif gen_number == 3
                if state == 1
                    app.Gen_3_status=1;
                    app.gen_3_img.BackgroundColor = 'r';
                else
                    app.Gen_3_status=0;
                    app.gen_3_img.BackgroundColor = 'g';
                end
            else
                if state == 1
                    app.Gen_4_status=1;
                    app.gen_4_img.BackgroundColor = 'r';
                else
                    app.Gen_4_status=0;
                    app.gen_4_img.BackgroundColor = 'g';
                end
            end
        end
        %}

        function motor_1_status_change(app)
            if (app.Breaker_3_status == 1)
                if (app.Breaker_1_status==1 && app.Breaker_2_status ==0)
                    if (app.Gen_1_status==1 || app.Gen_2_status ==1)
                        app.motor_1_img.BackgroundColor ='r';
                        app.Motor_1_status = 1;
                    else
                        app.motor_1_img.BackgroundColor ='g';
                        app.Motor_1_status = 0;
                    end
                elseif (app.Breaker_1_status==1 && app.Breaker_2_status==1)
                    if (app.Gen_1_status==1 || app.Gen_2_status ==1)
                        app.motor_1_img.BackgroundColor ='r';
                        app.Motor_1_status = 1;
                    elseif(app.Gen_3_status==1||app.Gen_4_status==1)
                        app.motor_1_img.BackgroundColor ='r';
                        app.Motor_1_status = 1;
                    else
                        app.motor_1_img.BackgroundColor ='g';
                        app.Motor_1_status = 0;
                    end
                elseif (app.Breaker_1_status==0 && app.Breaker_2_status==1)
                    if (app.Gen_3_status==1 || app.Gen_4_status ==1)
                        app.motor_1_img.BackgroundColor ='r';
                        app.Motor_1_status = 1;
                    else
                        app.motor_1_img.BackgroundColor ='g';
                        app.Motor_1_status = 0;
                    end
                else
                    app.motor_1_img.BackgroundColor ='g';
                    app.Motor_1_status = 0;
                end

            else
                app.motor_1_img.BackgroundColor ='g';
                app.Motor_1_status = 0;
            end

        end

        function motor_2_status_change(app)
            if (app.Breaker_4_status == 1)
                if (app.Breaker_1_status==1 && app.Breaker_2_status ==0)
                    if (app.Gen_1_status==1 || app.Gen_2_status ==1)
                        app.motor_2_img.BackgroundColor ='r';
                        app.Motor_2_status = 1;
                    else
                        app.motor_2_img.BackgroundColor ='g';
                        app.Motor_2_status = 0;
                    end
                elseif (app.Breaker_1_status==1 && app.Breaker_2_status==1)
                    if (app.Gen_1_status==1 || app.Gen_2_status ==1)
                        app.motor_2_img.BackgroundColor ='r';
                        app.Motor_2_status = 1;
                    elseif(app.Gen_3_status==1||app.Gen_4_status==1)
                        app.motor_2_img.BackgroundColor ='r';
                        app.Motor_2_status = 1;
                    else
                        app.motor_2_img.BackgroundColor ='g';
                        app.Motor_2_status = 0;
                    end
                elseif (app.Breaker_1_status==0 && app.Breaker_2_status==1)
                    if (app.Gen_3_status==1 || app.Gen_4_status ==1)
                        app.motor_2_img.BackgroundColor ='r';
                        app.Motor_2_status = 1;
                    else
                        app.motor_2_img.BackgroundColor ='g';
                        app.Motor_2_status = 0;
                    end
                else
                    app.motor_2_img.BackgroundColor ='g';
                    app.Motor_2_status = 0;
                end

            else
                app.motor_2_img.BackgroundColor ='g';
                app.Motor_2_status = 0;
            end
        end
        
        function change_simulation_state(app)
            if app.sim_state == 1
                app.simulation_state_label.Text ='ON-LINE';
                app.simulation_state_label.BackgroundColor ='r';
            else
                app.simulation_state_label.Text ='SIMULATION';
                app.simulation_state_label.BackgroundColor ='G';
            end
        end
    
        function change_condition_state(app)
            if app.condition_1 == 1
                app.condition_1_label.BackgroundColor = 'g';
            else
                app.condition_1_label.BackgroundColor = 'r';
            end

            if app.condition_2 == 1
                app.condition_2_label.BackgroundColor = 'g';
            else
                app.condition_2_label.BackgroundColor = 'r';
            end

            if app.condition_3 == 1
                app.condition_3_label.BackgroundColor = 'g';
            else
                app.condition_3_label.BackgroundColor = 'r';
            end
        end
    
        function break_by_power(app)
            if (app.Breaker_3_status == 1 && app.Breaker_1_status ==1 && app.Breaker_4_status == 0)
                if app.Gen_1_status == 1 && app.Gen_2_status ==0
                    if (app.p_motor_1 >= (60/100) * app.p_gen_1)
                        app.Breaker_3_status = 0;
                        change_color_break_3(app);
                        motor_1_status_change(app);
                    end
                    app.load_gen_1_EditField.Value = app.p_gen_1-(app.p_gen_1 - app.p_motor_1);
                elseif app.Gen_1_status == 1 && app.Gen_2_status ==1
                    if ((app.p_motor_1)/2 >= ((60/100) * (app.p_gen_1+app.p_gen_2)/2))
                        app.Breaker_3_status = 0;
                        change_color_break_3(app);
                        motor_1_status_change(app);
                    end
                    app.load_gen_1_EditField.Value = app.p_gen_1-(app.p_gen_1 - (app.p_motor_1/2));
                    app.load_gen_2_EditField.Value = app.p_gen_2-(app.p_gen_2 - (app.p_motor_1/2));
                end
               
            end
            run_script(app);
        end

        function run_script(app)
            %initpsat;
            main_script;
            %app.load_gen_1_EditField.Value = round(Bus.Pg(1)*100,2);
            app.P_gen_1_EditField.Value = Syn.p(1);
            app.P_gen_2_EditField.Value = Syn.p(2);
            app.P_gen_3_EditField.Value = Syn.p(3);
            app.P_gen_4_EditField.Value = Syn.p(4);
            app.P_motor_1_EditField.Value = Ind.dat(4);
            app.P_motor_2_EditField.Value = Ind.dat(8);
        end
        
    end
    
    methods (Access = public)
        
        function importdata(app,condition_1,condition_2,condition_3)
            app.condition_1 = condition_1;
            app.condition_2 = condition_2;
            app.condition_3 = condition_3;
            change_condition_state(app);
        end

        function importdatagen_1(app,p,v,l,i)
            app.p_gen_1 = p;
            app.v_gen_1 = v;
            app.i_gen_1 = i;
            app.l_gen_1 = l;
            app.P_gen_1_EditField.Value = app.p_gen_1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Button pushed function: gen_1_button
        function gen_1_buttonButtonPushed(app, event)
            all_condition_check(app);
            if app.all_condition == 1
                change_status_gen_1(app);
                change_color_gen_1(app);
                motor_1_status_change(app);
                motor_2_status_change(app);
            end
        end

        % Button pushed function: gen_2_button
        function gen_2_buttonButtonPushed(app, event)
            all_condition_check(app);
            if app.all_condition == 1
                change_status_gen_2(app);
                change_color_gen_2(app);
                motor_1_status_change(app);
                motor_2_status_change(app);
            end
        end

        % Button pushed function: gen_3_button
        function gen_3_buttonButtonPushed(app, event)
            all_condition_check(app);
            if app.all_condition == 1
                change_status_gen_3(app);
                change_color_gen_3(app);
                motor_1_status_change(app);
                motor_2_status_change(app);
            end
        end

        % Button pushed function: gen_4_button
        function gen_4_buttonButtonPushed(app, event)
            all_condition_check(app);
            if app.all_condition == 1
                change_status_gen_4(app);
                change_color_gen_4(app);
                motor_1_status_change(app);
                motor_2_status_change(app);
            end    
        end

        % Button pushed function: breaker_1_button
        function breaker_1_buttonButtonPushed(app, event)
            change_status_break_1(app);
            change_color_break_1(app);
            motor_1_status_change(app);
            motor_2_status_change(app);
        end

        % Button pushed function: breaker_2_button
        function breaker_2_buttonButtonPushed(app, event)
            change_status_break_2(app);
            change_color_break_2(app);
            motor_1_status_change(app);
            motor_2_status_change(app);
        end

        % Button pushed function: breaker_3_button
        function breaker_3_buttonButtonPushed(app, event)
            change_status_break_3(app);
            change_color_break_3(app);
            motor_1_status_change(app);
        end

        % Button pushed function: breaker_4_button
        function breaker_4_buttonButtonPushed(app, event)
            change_status_break_4(app);
            change_color_break_4(app);
            motor_2_status_change(app);
        end

        % Button pushed function: ForceConditionButton
        function ForceConditionButtonPushed(app, event)
            app.requirement = requirement(app);     %#ok 
            importdata(app.requirement,app.condition_1,app.condition_2,app.condition_3); %#ok
        end

        % Image clicked function: gen_1_img
        function gen_1_imgImageClicked(app, event)
            app.generator = generator(app,1,app.Gen_1_status,app.p_gen_1,app.v_gen_1,app.l_gen_1,app.i_gen_1);     %#ok
        end

        % Image clicked function: gen_2_img
        function gen_2_imgImageClicked(app, event)
            app.generator = generator(app,2,app.Gen_2_status);     %#ok
        end

        % Image clicked function: gen_3_img
        function gen_3_imgImageClicked(app, event)
            app.generator = generator(app,3,app.Gen_3_status);     %#ok
        end

        % Image clicked function: gen_4_img
        function gen_4_imgImageClicked(app, event)
            app.generator = generator(app,4,app.Gen_4_status);     %#ok
        end

        % Image clicked function: motor_1_img
        function motor_1_imgImageClicked(app, event)
            app.motor=motor(app);   %#ok
        end

        % Image clicked function: motor_2_img
        function motor_2_imgImageClicked(app, event)
            app.motor=motor(app);   %#ok
        end

        % Button pushed function: SimulationOnLineButton
        function SimulationOnLineButtonPushed(app, event)
            if app.sim_state == 1
                app.sim_state = 0;
            else
                app.sim_state = 1;
            end
            change_simulation_state(app);
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            delete(app);
        end

        % Image clicked function: trafo_1_img
        function trafo_1_imgImageClicked(app, event)
            app.transformer = transformer(app);%#ok
        end

        % Button pushed function: OPCDataButton
        function OPCDataButtonPushed(app, event)
            app.opc = opc(app);%#ok
        end

        % Key press function: PSATSimulationUIFigure
        function PSATSimulationUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'escape')
                delete(app);
            end
        end

        % Image clicked function: trafo_2_img
        function trafo_2_imgImageClicked(app, event)
            app.opc = transformer(app);%#ok
        end

        % Button pushed function: StartPowerFlowButton
        function StartPowerFlowButtonPushed(app, event)
           break_by_power(app);
        end

        % Value changed function: P_gen_1_EditField
        function P_gen_1_EditFieldValueChanged(app, event)
            value = app.P_gen_1_EditField.Value;
            app.p_gen_1 = value;
        end

        % Value changed function: P_motor_1_EditField
        function P_motor_1_EditFieldValueChanged(app, event)
            value = app.P_motor_1_EditField.Value;
            app.p_motor_1 = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PSATSimulationUIFigure = uifigure('Visible', 'off');
            app.PSATSimulationUIFigure.Name = 'PSAT Simulation';
            app.PSATSimulationUIFigure.Icon = fullfile(pathToMLAPP, 'Assets', 'logo cinovasi.png');
            app.PSATSimulationUIFigure.KeyPressFcn = createCallbackFcn(app, @PSATSimulationUIFigureKeyPress, true);
            app.Image = uiimage(app.PSATSimulationUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Base Asset.png');
            app.control_panel = uipanel(app.PSATSimulationUIFigure);
            app.breaker_1_button = uibutton(app.control_panel, 'push');
            app.breaker_1_button.ButtonPushedFcn = createCallbackFcn(app, @breaker_1_buttonButtonPushed, true);
            app.breaker_1_button.Text = 'Open/Close Breaker 1';
            app.breaker_2_button = uibutton(app.control_panel, 'push');
            app.breaker_2_button.ButtonPushedFcn = createCallbackFcn(app, @breaker_2_buttonButtonPushed, true);
            app.breaker_2_button.Text = 'Open/Close Breaker 2';
            app.breaker_3_button = uibutton(app.control_panel, 'push');
            app.breaker_3_button.ButtonPushedFcn = createCallbackFcn(app, @breaker_3_buttonButtonPushed, true);
            app.breaker_3_button.Text = 'Open/Close Breaker 3';
            app.breaker_4_button = uibutton(app.control_panel, 'push');
            app.breaker_4_button.ButtonPushedFcn = createCallbackFcn(app, @breaker_4_buttonButtonPushed, true);
            app.breaker_4_button.Text = 'Open/Close Breaker 4';
            app.gen_1_button = uibutton(app.control_panel, 'push');
            app.gen_1_button.ButtonPushedFcn = createCallbackFcn(app, @gen_1_buttonButtonPushed, true);
            app.gen_1_button.Text = 'Start/Stop Gen 1';
            app.gen_2_button = uibutton(app.control_panel, 'push');
            app.gen_2_button.ButtonPushedFcn = createCallbackFcn(app, @gen_2_buttonButtonPushed, true);
            app.gen_2_button.Text = 'Start/Stop Gen 2';
            app.gen_4_button = uibutton(app.control_panel, 'push');
            app.gen_4_button.ButtonPushedFcn = createCallbackFcn(app, @gen_4_buttonButtonPushed, true);
            app.gen_4_button.Text = 'Start/Stop Gen 4';
            app.gen_3_button = uibutton(app.control_panel, 'push');
            app.gen_3_button.ButtonPushedFcn = createCallbackFcn(app, @gen_3_buttonButtonPushed, true);
            app.gen_3_button.Text = 'Start/Stop Gen 3';
            app.condition_1_label = uilabel(app.PSATSimulationUIFigure);
            app.condition_1_label.Text = 'GENERATOR BRAKES POSITION IS OFF';
            app.condition_2_label = uilabel(app.PSATSimulationUIFigure);
            app.condition_2_label.Text = 'UNIT BREAKING SYSTEM STATUS IS READY';
            app.condition_3_label = uilabel(app.PSATSimulationUIFigure);
            app.condition_3_label.Text = 'UNIT AUXILIARIES READY FOR START';
            app.trafo_2_label = uilabel(app.PSATSimulationUIFigure);
            app.trafo_2_label.Text = {'Trafo 1'; '500/19.5 kV'};
            app.trafo_1_label = uilabel(app.PSATSimulationUIFigure);
            app.trafo_1_label.Text = {'Trafo 2'; '500/19.5 kV'};
            app.gen_1_img = uiimage(app.PSATSimulationUIFigure);
            app.gen_1_img.ImageClickedFcn = createCallbackFcn(app, @gen_1_imgImageClicked, true);
            app.gen_1_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator.png');
            app.gen_2_img = uiimage(app.PSATSimulationUIFigure);
            app.gen_2_img.ImageClickedFcn = createCallbackFcn(app, @gen_2_imgImageClicked, true);
            app.gen_2_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator.png');
            app.gen_3_img = uiimage(app.PSATSimulationUIFigure);
            app.gen_3_img.ImageClickedFcn = createCallbackFcn(app, @gen_3_imgImageClicked, true);
            app.gen_3_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator.png');
            app.gen_4_img = uiimage(app.PSATSimulationUIFigure);
            app.gen_4_img.ImageClickedFcn = createCallbackFcn(app, @gen_4_imgImageClicked, true);
            app.gen_4_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator.png');
            app.breaker_1_img = uiimage(app.PSATSimulationUIFigure);
            app.breaker_1_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Breaker.png');
            app.breaker_2_img = uiimage(app.PSATSimulationUIFigure);
            app.breaker_2_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Breaker.png');
            app.breaker_4_img = uiimage(app.PSATSimulationUIFigure);
            app.breaker_4_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Breaker.png');
            app.breaker_3_img = uiimage(app.PSATSimulationUIFigure);
            app.breaker_3_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Breaker.png');
            app.breaker_3_label = uilabel(app.PSATSimulationUIFigure);
            app.breaker_3_label.Text = 'Breaker 3';
            app.breaker_1_label = uilabel(app.PSATSimulationUIFigure);
            app.breaker_1_label.Text = 'Breaker 1';
            app.breaker_2_label = uilabel(app.PSATSimulationUIFigure);
            app.breaker_2_label.Text = 'Breaker 2';
            app.simulation_state_label = uilabel(app.PSATSimulationUIFigure);
            app.simulation_state_label.Text = 'SIMULATION';
            app.PEditFieldLabel = uilabel(app.PSATSimulationUIFigure);
            app.PEditFieldLabel.Text = 'P:';
            app.P_gen_1_EditField = uieditfield(app.PSATSimulationUIFigure, 'numeric');
            app.P_gen_1_EditField.ValueChangedFcn = createCallbackFcn(app, @P_gen_1_EditFieldValueChanged, true);
            app.unit_gen_1_EditField = uieditfield(app.PSATSimulationUIFigure, 'text');
            app.unit_gen_1_EditField.Value = 'MW';
            app.PEditFieldLabel_2 = uilabel(app.PSATSimulationUIFigure);
            app.PEditFieldLabel_2.Text = 'P:';
            app.P_gen_2_EditField = uieditfield(app.PSATSimulationUIFigure, 'numeric');
            app.unit_gen_2_EditField = uieditfield(app.PSATSimulationUIFigure, 'text');
            app.unit_gen_2_EditField.Value = 'MW';
            app.PEditFieldLabel_3 = uilabel(app.PSATSimulationUIFigure);
            app.PEditFieldLabel_3.Text = 'P:';
            app.P_gen_3_EditField = uieditfield(app.PSATSimulationUIFigure, 'numeric');
            app.unit_gen_3_EditField = uieditfield(app.PSATSimulationUIFigure, 'text');
            app.unit_gen_3_EditField.Value = 'MW';
            app.PEditFieldLabel_4 = uilabel(app.PSATSimulationUIFigure);
            app.PEditFieldLabel_4.Text = 'P:';
            app.P_gen_4_EditField = uieditfield(app.PSATSimulationUIFigure, 'numeric');
            app.unit_gen_1_EditField_4 = uieditfield(app.PSATSimulationUIFigure, 'text');
            app.unit_gen_1_EditField_4.Value = 'MW';
            app.PEditFieldLabel_5 = uilabel(app.PSATSimulationUIFigure);
            app.PEditFieldLabel_5.Text = 'P:';
            app.P_motor_1_EditField = uieditfield(app.PSATSimulationUIFigure, 'numeric');
            app.P_motor_1_EditField.ValueChangedFcn = createCallbackFcn(app, @P_motor_1_EditFieldValueChanged, true);
            app.unit_motor_1_EditField = uieditfield(app.PSATSimulationUIFigure, 'text');
            app.unit_motor_1_EditField.Value = 'MW';
            app.P_motor_2_EditField = uieditfield(app.PSATSimulationUIFigure, 'numeric');
            app.PEditFieldLabel_6 = uilabel(app.PSATSimulationUIFigure);
            app.PEditFieldLabel_6.Text = 'P:';
            app.unit_motor_2_EditField = uieditfield(app.PSATSimulationUIFigure, 'text');
            app.unit_motor_2_EditField.Value = 'MW';
            app.breaker_4_label = uilabel(app.PSATSimulationUIFigure);
            app.breaker_4_label.Text = 'Breaker 4';
            app.motor_1_img = uiimage(app.PSATSimulationUIFigure);
            app.motor_1_img.ImageClickedFcn = createCallbackFcn(app, @motor_1_imgImageClicked, true);
            app.motor_1_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Motor.png');
            app.motor_2_img = uiimage(app.PSATSimulationUIFigure);
            app.motor_2_img.ImageClickedFcn = createCallbackFcn(app, @motor_2_imgImageClicked, true);
            app.motor_2_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Motor.png');
            app.ForceConditionButton = uibutton(app.PSATSimulationUIFigure, 'push');
            app.ForceConditionButton.ButtonPushedFcn = createCallbackFcn(app, @ForceConditionButtonPushed, true);
            app.ForceConditionButton.Text = 'Force Condition';
            app.SimulationOnLineButton = uibutton(app.PSATSimulationUIFigure, 'push');
            app.SimulationOnLineButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationOnLineButtonPushed, true);
            app.SimulationOnLineButton.Text = 'Simulation/On-Line';
            app.StartPowerFlowButton = uibutton(app.PSATSimulationUIFigure, 'push');
            app.StartPowerFlowButton.ButtonPushedFcn = createCallbackFcn(app, @StartPowerFlowButtonPushed, true);
            app.StartPowerFlowButton.Text = 'Start Power Flow';
            app.OPCDataButton = uibutton(app.PSATSimulationUIFigure, 'push');
            app.OPCDataButton.ButtonPushedFcn = createCallbackFcn(app, @OPCDataButtonPushed, true);
            app.OPCDataButton.Text = 'OPC Data';
            app.load_gen_1_unit = uieditfield(app.PSATSimulationUIFigure, 'text');
            app.load_gen_1_unit.Value = '%';
            app.LoadLabel = uilabel(app.PSATSimulationUIFigure);
            app.LoadLabel.Text = 'L:';
            app.load_gen_1_EditField = uieditfield(app.PSATSimulationUIFigure, 'numeric');
            app.load_gen_2_unit = uieditfield(app.PSATSimulationUIFigure, 'text');
            app.load_gen_2_unit.Value = '%';
            app.LoadLabel_2 = uilabel(app.PSATSimulationUIFigure);
            app.LoadLabel_2.Text = 'L:';
            app.load_gen_2_EditField = uieditfield(app.PSATSimulationUIFigure, 'numeric');
            app.load_gen_3_unit = uieditfield(app.PSATSimulationUIFigure, 'text');
            app.load_gen_3_unit.Value = '%';
            app.LLabel = uilabel(app.PSATSimulationUIFigure);
            app.LLabel.Text = 'L:';
            app.load_gen_3_EditField = uieditfield(app.PSATSimulationUIFigure, 'numeric');
            app.load_gen_4_unit = uieditfield(app.PSATSimulationUIFigure, 'text');
            app.load_gen_4_unit.Value = '%';
            app.LLabel_2 = uilabel(app.PSATSimulationUIFigure);
            app.LLabel_2.Text = 'L:';
            app.load_gen_4_EditField = uieditfield(app.PSATSimulationUIFigure, 'numeric');
            app.trafo_1_img = uiimage(app.PSATSimulationUIFigure);
            app.trafo_1_img.ImageClickedFcn = createCallbackFcn(app, @trafo_1_imgImageClicked, true);
            app.trafo_1_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Transformer.png');
            app.trafo_2_img = uiimage(app.PSATSimulationUIFigure);
            app.trafo_2_img.ImageClickedFcn = createCallbackFcn(app, @trafo_2_imgImageClicked, true);
            app.trafo_2_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Transformer.png');
            app.ContextMenu = uicontextmenu(app.PSATSimulationUIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.SettingsMenu = uimenu(app.ContextMenu);
            app.SettingsMenu.Text = 'Settings';
            app.CloseMenu = uimenu(app.ContextMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            % Assign app.ContextMenu
            app.Image.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.PSATSimulationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guisimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PSATSimulationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PSATSimulationUIFigure)
        end
    end
end