classdef motor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotorUIFigure                  matlab.ui.Figure
        SaveButton                     matlab.ui.control.Button
        title                          matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        f_motor_rating_EditField       matlab.ui.control.NumericEditField
        f_unit_motor_rating_EditField  matlab.ui.control.EditField
        p_motor_EditField              matlab.ui.control.NumericEditField
        p_unit_motor_EditField         matlab.ui.control.EditField
        l_motor_EditField              matlab.ui.control.NumericEditField
        l_unit_motor_EditField         matlab.ui.control.EditField
        i_motor_EditField              matlab.ui.control.NumericEditField
        i_unit_motor_EditField         matlab.ui.control.EditField
        v_motor_rating_EditField       matlab.ui.control.NumericEditField
        v_unit_motor_rating_EditField  matlab.ui.control.EditField
        q_motor_EditField              matlab.ui.control.NumericEditField
        q_unit_motor_EditField         matlab.ui.control.EditField
        s_motor_rating_EditField       matlab.ui.control.NumericEditField
        s_unit_motor_rating_EditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        callingapp % Description
        motor_id;
        motor_state;
        s_motor_rating;
        v_motor_rating;
        f_motor_rating;
        l_motor;
        i_motor;
        p_motor;
        q_motor;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, guisimulation, motor_id, motor_state, s_rating, v_rating, f_rating, l, i, p, q)
            app.callingapp = guisimulation;
            app.motor_id = num2str(motor_id);
            app.title.Text = strcat(app.title.Text,app.motor_id);

            %variable
            app.s_motor_rating = s_rating;
            app.v_motor_rating = v_rating;
            app.f_motor_rating = f_rating;
            app.l_motor = l;
            app.i_motor = i;
            app.p_motor = p;
            app.q_motor = q;

            %display value
            app.s_motor_rating_EditField.Value = s_rating;
            app.v_motor_rating_EditField.Value = v_rating;
            app.f_motor_rating_EditField.Value = f_rating;
            app.l_motor_EditField.Value = l;
            app.i_motor_EditField.Value = i;
            app.p_motor_EditField.Value = p;
            app.q_motor_EditField.Value = q;
        end

        % Key press function: MotorUIFigure
        function MotorUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'escape')
                delete(app);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            s_rating = app.s_motor_rating_EditField.Value;
            v_rating = app.v_motor_rating_EditField.Value;
            f_rating = app.f_motor_rating_EditField.Value;
            i = app.i_motor_EditField.Value;
            l = app.l_motor_EditField.Value;
            p = app.p_motor_EditField.Value;
            q = app.q_motor_EditField.Value;
            if strcmp(app.motor_id,'K1-M')
                importdata_motor_k1(app.callingapp,s_rating, v_rating, f_rating, l, i, p, q)
            elseif strcmp(app.motor_id,'K3-M')
                importdata_motor_k3(app.callingapp,s_rating, v_rating, f_rating, l, i, p, q)
            elseif strcmp(app.motor_id,'K2-M')
                importdata_motor_k2(app.callingapp,s_rating, v_rating, f_rating, l, i, p, q)
            elseif strcmp(app.motor_id,'K4-M')
                importdata_motor_k4(app.callingapp,s_rating, v_rating, f_rating, l, i, p, q)
            else
                
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotorUIFigure = uifigure('Visible', 'off');
            app.MotorUIFigure.Name = 'Motor';
            app.MotorUIFigure.KeyPressFcn = createCallbackFcn(app, @MotorUIFigureKeyPress, true);
            app.ButtonGroup = uibuttongroup(app.MotorUIFigure);
            app.s_unit_motor_rating_EditField = uieditfield(app.ButtonGroup, 'text');
            app.s_unit_motor_rating_EditField.Value = 'kVa';
            app.s_motor_rating_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.q_unit_motor_EditField = uieditfield(app.ButtonGroup, 'text');
            app.q_unit_motor_EditField.Value = 'kVar';
            app.q_motor_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.v_unit_motor_rating_EditField = uieditfield(app.ButtonGroup, 'text');
            app.v_unit_motor_rating_EditField.Value = 'kV';
            app.v_motor_rating_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.i_unit_motor_EditField = uieditfield(app.ButtonGroup, 'text');
            app.i_unit_motor_EditField.Value = 'A';
            app.i_motor_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.l_unit_motor_EditField = uieditfield(app.ButtonGroup, 'text');
            app.l_unit_motor_EditField.Value = '%';
            app.l_motor_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.p_unit_motor_EditField = uieditfield(app.ButtonGroup, 'text');
            app.p_unit_motor_EditField.Value = 'kW';
            app.p_motor_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.f_unit_motor_rating_EditField = uieditfield(app.ButtonGroup, 'text');
            app.f_unit_motor_rating_EditField.Value = 'Hz';
            app.f_motor_rating_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.title = uilabel(app.MotorUIFigure);
            app.title.Text = 'MOTOR UNIT # ';
            app.SaveButton = uibutton(app.MotorUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.MotorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = motor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotorUIFigure)
        end
    end
end