classdef psathmi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image3                     matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        Label_2                    matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        voltageC01BValue           matlab.ui.control.Label
        voltageC01BUnit            matlab.ui.control.Label
        frequencyC01BValue         matlab.ui.control.Label
        frequencyC01BUnit          matlab.ui.control.Label
        voltageC01AValue           matlab.ui.control.Label
        voltageC01AUnit            matlab.ui.control.Label
        frequencyC01AValue         matlab.ui.control.Label
        frequencyC01AUnit          matlab.ui.control.Label
        powerTrAc01BValue          matlab.ui.control.Label
        powerTrAc01BUnit           matlab.ui.control.Label
        reactiveTrAc01BValue       matlab.ui.control.Label
        reactiveTrAc01BUnit        matlab.ui.control.Label
        currentTrAc01BValue        matlab.ui.control.Label
        currentTrAc01BUnit         matlab.ui.control.Label
        voltageTrAc01BValue        matlab.ui.control.Label
        voltagetTrAc01BUnit        matlab.ui.control.Label
        powerTrAc01AValue          matlab.ui.control.Label
        powerTrAc01AUnit           matlab.ui.control.Label
        reactiveTrAc01AValue       matlab.ui.control.Label
        reactiveTrAc01AUnit        matlab.ui.control.Label
        currentTrAc01AValue        matlab.ui.control.Label
        currentTrAc01AUnit         matlab.ui.control.Label
        voltageTrAc01AValue        matlab.ui.control.Label
        voltagetTrAc01AUnit        matlab.ui.control.Label
        frequencyGt01Value         matlab.ui.control.Label
        voltageGt01Unit            matlab.ui.control.Label
        voltageGt01Value           matlab.ui.control.Label
        currentGt01Unit            matlab.ui.control.Label
        currentGt01Value           matlab.ui.control.Label
        activeGt01Unit             matlab.ui.control.Label
        activeGt01Value            matlab.ui.control.Label
        reactiveGt01Unit           matlab.ui.control.Label
        reactiveGt01Value          matlab.ui.control.Label
        powerGt01Unit              matlab.ui.control.Label
        powerGt01Value             matlab.ui.control.Label
        frequencyGt01Unit          matlab.ui.control.Label
        powerSg03Value             matlab.ui.control.Label
        powerSg03Unit              matlab.ui.control.Label
        reactiveSg03Value          matlab.ui.control.Label
        reactiveSg03Unit           matlab.ui.control.Label
        activeSg03Value            matlab.ui.control.Label
        activeSg03Unit             matlab.ui.control.Label
        currentSg03Value           matlab.ui.control.Label
        currentSg03Unit            matlab.ui.control.Label
        voltageSg03Value           matlab.ui.control.Label
        voltageSg03Unit            matlab.ui.control.Label
        frequencySg03Value         matlab.ui.control.Label
        frequencySg03Unit          matlab.ui.control.Label
        frequencySg02Unit          matlab.ui.control.Label
        frequencySg02Value         matlab.ui.control.Label
        voltageSg02Unit            matlab.ui.control.Label
        voltageSg02Value           matlab.ui.control.Label
        currentSg02Unit            matlab.ui.control.Label
        currentSg02Value           matlab.ui.control.Label
        activeSg02Unit             matlab.ui.control.Label
        activeSg02Value            matlab.ui.control.Label
        reactiveSg02Unit           matlab.ui.control.Label
        reactiveSg02Value          matlab.ui.control.Label
        powerSg02Unit              matlab.ui.control.Label
        powerSg02Value             matlab.ui.control.Label
        powerSg01Value             matlab.ui.control.Label
        powerSg01Unit              matlab.ui.control.Label
        reactiveSg01Value          matlab.ui.control.Label
        reactiveSg01Unit           matlab.ui.control.Label
        activeSg01Value            matlab.ui.control.Label
        activeSg01Unit             matlab.ui.control.Label
        currentSg01Value           matlab.ui.control.Label
        currentSg01Unit            matlab.ui.control.Label
        voltageSg01Value           matlab.ui.control.Label
        voltageSg01Unit            matlab.ui.control.Label
        frequencySg01Value         matlab.ui.control.Label
        frequencySg01Unit          matlab.ui.control.Label
        condition_1_label          matlab.ui.control.Label
        condition_2_label          matlab.ui.control.Label
        condition_3_label          matlab.ui.control.Label
        ForceConditionButton       matlab.ui.control.Button
        SimulationOnLineButton     matlab.ui.control.Button
        OPCButton                  matlab.ui.control.Button
        StartPowerFlowButton       matlab.ui.control.Button
        LoadEvalButton             matlab.ui.control.Button
        Sg01Img                    matlab.ui.control.Image
        Sg02Img                    matlab.ui.control.Image
        Sg03Img                    matlab.ui.control.Image
        Sg04Img                    matlab.ui.control.Image
        TrafoSg01Img               matlab.ui.control.Image
        TrafoSg02Img               matlab.ui.control.Image
        TrafoSg03Img               matlab.ui.control.Image
        TrafoSg04Img               matlab.ui.control.Image
        SwitchSg01Img              matlab.ui.control.Image
        SwitchSg02Img              matlab.ui.control.Image
        SwitchSg03Img              matlab.ui.control.Image
        SwitchSg04Img              matlab.ui.control.Image
        BusA02Img                  matlab.ui.control.Image
        BusA03Img                  matlab.ui.control.Image
        SwitchSTAImg               matlab.ui.control.Image
        SwitchSTBImg               matlab.ui.control.Image
        TrafoAC01AImg              matlab.ui.control.Image
        TrafoAC01BImg              matlab.ui.control.Image
        SwitchAC01AImg             matlab.ui.control.Image
        SwitchAC01BImg             matlab.ui.control.Image
        BusC01AImg                 matlab.ui.control.Image
        BusC01BImg                 matlab.ui.control.Image
        SwitchSMAImg               matlab.ui.control.Image
        SwitchSMBImg               matlab.ui.control.Image
        SwitchSMCImg               matlab.ui.control.Image
        SwitchSMDImg               matlab.ui.control.Image
        k1Img                      matlab.ui.control.Image
        k2Img                      matlab.ui.control.Image
        k3Img                      matlab.ui.control.Image
        k4Img                      matlab.ui.control.Image
        SwitchC01Img               matlab.ui.control.Image
        StartStopSG01Button        matlab.ui.control.Button
        StartStopSG02Button        matlab.ui.control.Button
        StartStopSG03Button        matlab.ui.control.Button
        StartStopGT01Button        matlab.ui.control.Button
        OpenCloseSwitchSG01Button  matlab.ui.control.Button
        OpenCloseSwitchSG02Button  matlab.ui.control.Button
        OpenCloseSwitchSG03Button  matlab.ui.control.Button
        OpenCloseSwitchGT01Button  matlab.ui.control.Button
        OpenCloseSTAButton         matlab.ui.control.Button
        OpenCloseSTBButton         matlab.ui.control.Button
        OpenCloseSwAC01BButton     matlab.ui.control.Button
        OpenCloseSwAC01AButton     matlab.ui.control.Button
        OpenCloseSMAButton         matlab.ui.control.Button
        OpenCloseSMBButton         matlab.ui.control.Button
        OpenCloseSMCButton         matlab.ui.control.Button
        OpenCloseSMDButton         matlab.ui.control.Button
        OpenCloseSwitchC01Button   matlab.ui.control.Button
        Background                 matlab.ui.control.Image
        Label                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        %instance
        generator_instance;
        transformer_instance;
        motor_instance;
        bus_instance;
        %variable
        Sg01Status = 0;
        TrafoSg01Status = 0;
        SwitchSg01Status = 0;
        Sg02Status = 0;
        TrafoSg02Status = 0;
        SwitchSg02Status = 0;
        Sg03Status = 0;
        TrafoSg03Status = 0;
        SwitchSg03Status = 0;
        Sg04Status = 0;
        TrafoSg04Status = 0;
        SwitchSg04Status = 0;
        BusA02Status = 0;
        BusA03Status = 0;
        SwitchSTAStatus = 0;
        SwitchSTBStatus = 0;
        TrafoAC01AStatus = 0;
        TrafoAC01BStatus = 0;
        SwitchAC01AStatus = 0;
        SwitchAC01BStatus = 0;
        BusC01AStatus = 0;
        BusC01BStatus = 0;
        SwitchSMAStatus = 0;
        SwitchSMBStatus = 0;
        SwitchSMCStatus = 0;
        SwitchSMDStatus = 0;
        K1Status = 0;
        K2Status = 0;
        K3Status = 0;
        K4Status = 0;
        SwitchC01Status = 1;
        condition_1 = 0;
        condition_2 = 0;
        condition_3 = 0;

        %sg01 variable
        sg01_power_rating = 37500;
        sg01_voltage_rating = 11;
        sg01_frequency_rating = 50;
        sg01_reactive = 0;
        sg01_active = 0;
        sg01_current = 0;
        sg01_load = 0;
        trasg01_power_rating = 40000;
        trasg01_voltage_rating = 11;
        trasg01_frequency_rating = 50;
        trasg01_voltage_from = 11;
        trasg01_voltage_to = 20;
        trasg01_reactive = 0;
        trasg01_active = 0;
        trasg01_current = 0;
        trasg01_load = 0;

        %sg02 variable
        sg02_power_rating = 37500;
        sg02_voltage_rating = 11;
        sg02_frequency_rating = 50;
        sg02_reactive = 0;
        sg02_active = 0;
        sg02_current = 0;
        sg02_load = 0;
        trasg02_power_rating = 40000;
        trasg02_voltage_rating = 11;
        trasg02_frequency_rating = 50;
        trasg02_voltage_from = 11;
        trasg02_voltage_to = 20;
        trasg02_reactive = 0;
        trasg02_active = 0;
        trasg02_current = 0;
        trasg02_load = 0;

        %sg03 variable
        sg03_power_rating = 37500;
        sg03_voltage_rating = 11;
        sg03_frequency_rating = 50;
        sg03_reactive = 0;
        sg03_active = 0;
        sg03_current = 0;
        sg03_load = 0;
        trasg03_power_rating = 40000;
        trasg03_voltage_rating = 11;
        trasg03_frequency_rating = 50;
        trasg03_voltage_from = 11;
        trasg03_voltage_to = 20;
        trasg03_reactive = 0;
        trasg03_active = 0;
        trasg03_current = 0;
        trasg03_load = 0;

        %gt01 variable
        gt01_power_rating = 37500;
        gt01_voltage_rating = 11;
        gt01_frequency_rating = 50;
        gt01_reactive = 0;
        gt01_active = 10;
        gt01_current = 0;
        gt01_load = 10;
        tragt01_power_rating = 40000;
        tragt01_voltage_rating = 11;
        tragt01_frequency_rating = 50;
        tragt01_voltage_from = 11;
        tragt01_voltage_to = 20;
        tragt01_reactive = 0;
        tragt01_active = 0;
        tragt01_current = 0;
        tragt01_load = 0;
        
        %bus A02 variable
        busa02_voltage_rating = 33;
        busa02_reactive_gen = 0;
        busa02_active_gen = 0;
        busa02_reactive_load = 0;
        busa02_active_load = 0;
        busa02_current = 0;
        busa02_frequency = 50;
        busa02_load = 0;

        %bus A03 variable
        busa03_voltage_rating = 33;
        busa03_reactive_gen = 0;
        busa03_active_gen = 0;
        busa03_reactive_load = 0;
        busa03_active_load = 0;
        busa03_current = 0;
        busa03_frequency = 50;
        busa03_load = 0;

        %bus C01A variable
        busc01a_voltage_rating = 6.6;
        busc01a_reactive_gen = 0;
        busc01a_active_gen = 0;
        busc01a_reactive_load = 0;
        busc01a_active_load = 0;
        busc01a_current = 0;
        busc01a_frequency = 50;
        busc01a_load = 0;

        %bus C01B variable
        busc01b_voltage_rating = 6.6;
        busc01b_reactive_gen = 0;
        busc01b_active_gen = 0;
        busc01b_reactive_load = 0;
        busc01b_active_load = 0;
        busc01b_current = 0;
        busc01b_frequency = 50;
        busc01b_load = 0;

        %TRAC01A variable
        trac01a_power_rating = 40000;
        trac01a_voltage_rating = 33;
        trac01a_frequency_rating = 50;
        trac01a_voltage_from = 33;
        trac01a_voltage_to = 6.9;
        trac01a_reactive = 0;
        trac01a_current = 0;
        trac01a_active = 0;
        trac01a_load = 0;

        %TRAC01B variable
        trac01b_power_rating = 40000;
        trac01b_voltage_rating = 33;
        trac01b_frequency_rating = 50;
        trac01b_voltage_from = 33;
        trac01b_voltage_to = 6.9;
        trac01b_reactive = 0;
        trac01b_current = 0;
        trac01b_active = 0;
        trac01b_load = 0;

        %Motor K3 variable
        k3_power_rating = 40000;
        k3_voltage_rating = 6.6;
        k3_frequency_rating = 50;
        k3_active = 0;
        k3_reactive = 0;
        k3_current = 0;
        k3_load = 0;

        %Motor K1 variable
        k1_power_rating = 40000;
        k1_voltage_rating =6.6;
        k1_frequency_rating = 50;
        k1_active = 0;
        k1_reactive = 0;
        k1_current = 0;
        k1_load = 0;

        %Motor K2 variable
        k2_power_rating = 40000;
        k2_voltage_rating = 6.6;
        k2_frequency_rating = 50;
        k2_active = 0;
        k2_reactive = 0;
        k2_current = 0;
        k2_load = 0;

        %Motor K4 variable
        k4_power_rating = 40000;
        k4_voltage_rating = 6.6;
        k4_frequency_rating = 50;
        k4_active = 0;
        k4_reactive = 0;
        k4_current = 0;
        k4_load = 0;

        %instance
        requirement;
    end
    
    methods (Access = private)
        
        %%Image Change
        %Image change SG01
        function statusSg01Check(app)
            if app.Sg01Status == 1
                app.Sg01Img.ImageSource = 'Assets/Generator - Run.png';
            else
                app.Sg01Img.ImageSource = 'Assets/Generator - Stop.png';
            end
        end
        function statusTrafoSg01Check(app)
            if app.TrafoSg01Status == 1
                app.TrafoSg01Img.ImageSource = 'Assets/Transformer Energize.png';
            else
                app.TrafoSg01Img.ImageSource = 'Assets/Transformer De-Energize.png';
            end
        end
        function statusSwitchSg01Check(app)
            if app.SwitchSg01Status == 1
                app.SwitchSg01Img.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSg01Img.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        %Image change SG02    
        function statusSg02Check(app)
            if app.Sg02Status == 1
                app.Sg02Img.ImageSource = 'Assets/Generator - Run.png';
            else
                app.Sg02Img.ImageSource = 'Assets/Generator - Stop.png';
            end
        end
        function statusTrafoSg02Check(app)
            if app.TrafoSg02Status == 1
                app.TrafoSg02Img.ImageSource = 'Assets/Transformer Energize.png';
            else
                app.TrafoSg02Img.ImageSource = 'Assets/Transformer De-Energize.png';
            end
        end
        function statusSwitchSg02Check(app)
            if app.SwitchSg02Status == 1
                app.SwitchSg02Img.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSg02Img.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        %Image change SG03    
        function statusSg03Check(app)
            if app.Sg03Status == 1
                app.Sg03Img.ImageSource = 'Assets/Generator - Run.png';
            else
                app.Sg03Img.ImageSource = 'Assets/Generator - Stop.png';
            end
        end
        function statusTrafoSg03Check(app)
            if app.TrafoSg03Status == 1
                app.TrafoSg03Img.ImageSource = 'Assets/Transformer Energize.png';
            else
                app.TrafoSg03Img.ImageSource = 'Assets/Transformer De-Energize.png';
            end
        end
        function statusSwitchSg03Check(app)
            if app.SwitchSg03Status == 1
                app.SwitchSg03Img.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSg03Img.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end

        %Image change SG04    
        function statusSg04Check(app)
            if app.Sg04Status == 1
                app.Sg04Img.ImageSource = 'Assets/Generator - Run.png';
            else
                app.Sg04Img.ImageSource = 'Assets/Generator - Stop.png';
            end
        end
        function statusTrafoSg04Check(app)
            if app.TrafoSg04Status == 1
                app.TrafoSg04Img.ImageSource = 'Assets/Transformer Energize.png';
            else
                app.TrafoSg04Img.ImageSource = 'Assets/Transformer De-Energize.png';
            end
        end
        function statusSwitchSg04Check(app)
            if app.SwitchSg04Status == 1
                app.SwitchSg04Img.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSg04Img.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        %Image Change Bus A02
        function statusBusA02Check(app)
            if app.BusA02Status == 1
                app.BusA02Img.ImageSource = 'Assets/Bus Energize.png';
            else
                app.BusA02Img.ImageSource = 'Assets/Bus De-Energize.png';
            end
        end
        %Image Change Bus A03
        function statusBusA03Check(app)
            if app.BusA03Status == 1
                app.BusA03Img.ImageSource = 'Assets/Bus Energize.png';
            else
                app.BusA03Img.ImageSource = 'Assets/Bus De-Energize.png';
            end
        end
        %Image Change AC01A
        function statusSwitchSTACheck(app)
            if app.SwitchSTAStatus == 1
                app.SwitchSTAImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSTAImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        function statusTrafoAC01ACheck(app)
            if app.TrafoAC01AStatus == 1
                app.TrafoAC01AImg.ImageSource = 'Assets/Transformer Energize.png';
            else
                app.TrafoAC01AImg.ImageSource = 'Assets/Transformer De-Energize.png';
            end
        end
        function statusSwitchAC01ACheck(app)
            if app.SwitchAC01AStatus == 1
                app.SwitchAC01AImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchAC01AImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end

        %Image Change AC01B
        function statusSwitchSTBCheck(app)
            if app.SwitchSTBStatus == 1
                app.SwitchSTBImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSTBImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        function statusTrafoAC01BCheck(app)
            if app.TrafoAC01BStatus == 1
                app.TrafoAC01BImg.ImageSource = 'Assets/Transformer Energize.png';
            else
                app.TrafoAC01BImg.ImageSource = 'Assets/Transformer De-Energize.png';
            end
        end
        function statusSwitchAC01BCheck(app)
            if app.SwitchAC01BStatus == 1
                app.SwitchAC01BImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchAC01BImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end

        %Image Change Bus C01A
        function statusBusC01ACheck(app)
            if app.BusC01AStatus == 1
                app.BusC01AImg.ImageSource = 'Assets/Bus Energize.png';
            else
                app.BusC01AImg.ImageSource = 'Assets/Bus De-Energize.png';
            end
        end
        %Image Change Bus C01B
        function statusBusC01BCheck(app)
            if app.BusC01BStatus == 1
                app.BusC01BImg.ImageSource = 'Assets/Bus Energize.png';
            else
                app.BusC01BImg.ImageSource = 'Assets/Bus De-Energize.png';
            end
        end
        %Image Change K3
        function statusSwitchSMACheck(app)
            if app.SwitchSMAStatus == 1
                app.SwitchSMAImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSMAImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
            
        end
        function statusk3Check(app)
            if app.K3Status == 1
                app.k3Img.ImageSource = 'Assets/Motor - Run.png';
            else
                app.k3Img.ImageSource = 'Assets/Motor - Stop.png';
            end
        end

        %Image Change K1
        function statusSwitchSMBCheck(app)
            if app.SwitchSMBStatus == 1
                app.SwitchSMBImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSMBImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        function statusk1Check(app)
            if app.K1Status == 1
                app.k1Img.ImageSource = 'Assets/Motor - Run.png';
            else
                app.k1Img.ImageSource = 'Assets/Motor - Stop.png';
            end
        end
        
        %Image Change K2
        function statusSwitchSMCCheck(app)
            if app.SwitchSMCStatus == 1
                app.SwitchSMCImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSMCImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        function statusk2Check(app)
            if app.K2Status == 1
                app.k2Img.ImageSource = 'Assets/Motor - Run.png';
            else
                app.k2Img.ImageSource = 'Assets/Motor - Stop.png';
            end
        end

        %Image Change K4
        function statusSwitchSMDCheck(app)
            if app.SwitchSMDStatus == 1
                app.SwitchSMDImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSMDImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        function statusk4Check(app)
            if app.K4Status == 1
                app.k4Img.ImageSource = 'Assets/Motor - Run.png';
            else
                app.k4Img.ImageSource = 'Assets/Motor - Stop.png';
            end
        end
        function statusSwitchC01Check(app)
            if app.SwitchC01Status == 1
                app.SwitchC01Img.ImageSource = 'Assets/380 Switch - Close (In Service) - 90.png';
            else
                app.SwitchC01Img.ImageSource = 'Assets/380 Switch - Open (In Service) - 90.png';
            end
        end
        
        %% Status Change

        %Change status SG01
        function changeSg01Status(app)
            if app.Sg01Status == 0
                app.Sg01Status = 1;
            else
                app.Sg01Status = 0;
            end
            statusSg01Check(app);
        end
        function changeTrafoSg01Status(app)
            if app.TrafoSg01Status == 0
                app.TrafoSg01Status = 1;
            else
                app.TrafoSg01Status = 0;
            end
            statusTrafoSg01Check(app);
        end
        function changeSwitchSg01Status(app)
            if app.SwitchSg01Status == 0
                app.SwitchSg01Status = 1;
            else
                app.SwitchSg01Status = 0;
            end
            statusSwitchSg01Check(app);
        end

        %Change status SG02
        function changeSg02Status(app)
            if app.Sg02Status == 0
                app.Sg02Status = 1;
            else
                app.Sg02Status = 0;
            end
            statusSg02Check(app);
        end
        function changeTrafoSg02Status(app)
            if app.TrafoSg02Status == 0
                app.TrafoSg02Status = 1;
            else
                app.TrafoSg02Status = 0;
            end
            statusTrafoSg02Check(app);
        end
        function changeSwitchSg02Status(app)
            if app.SwitchSg02Status == 0
                app.SwitchSg02Status = 1;
            else
                app.SwitchSg02Status = 0;
            end
            statusSwitchSg02Check(app);
        end

        %Change status SG03
        function changeSg03Status(app)
            if app.Sg03Status == 0
                app.Sg03Status = 1;
            else
                app.Sg03Status = 0;
            end
            statusSg03Check(app);
        end
        function changeTrafoSg03Status(app)
            if app.TrafoSg03Status == 0
                app.TrafoSg03Status = 1;
            else
                app.TrafoSg03Status = 0;
            end
            statusTrafoSg03Check(app);
        end
        function changeSwitchSg03Status(app)
            if app.SwitchSg03Status == 0
                app.SwitchSg03Status = 1;
            else
                app.SwitchSg03Status = 0;
            end
            statusSwitchSg03Check(app);
        end

        %Change status SG04
        function changeSg04Status(app)
            if app.Sg04Status == 0
                app.Sg04Status = 1;
            else
                app.Sg04Status = 0;
            end
            statusSg04Check(app);
        end
        function changeTrafoSg04Status(app)
            if app.TrafoSg04Status == 0
                app.TrafoSg04Status = 1;
            else
                app.TrafoSg04Status = 0;
            end
            statusTrafoSg04Check(app);
        end
        function changeSwitchSg04Status(app)
            if app.SwitchSg04Status == 0
                app.SwitchSg04Status = 1;
            else
                app.SwitchSg04Status = 0;
            end
            statusSwitchSg04Check(app);
            
        end
        
        %Change status Bus A02
        function changeBusA02Status(app)
            if app.BusA02Status == 0
                app.BusA02Status = 1;
            else
                app.BusA02Status = 0;
            end
        end
        %Change status Bus A03
        function changeBusA03Status(app)
            if app.BusA03Status == 0
                app.BusA03Status = 1;
            else
                app.BusA03Status = 0;
            end
        end

        %Change status AC01A
        function changeSwitchSTAStatus(app)
            if app.SwitchSTAStatus == 0
                app.SwitchSTAStatus = 1;
            else
                app.SwitchSTAStatus = 0;
            end
            statusSwitchSTACheck(app);
        end
        function changeTrafoAC01AStatus(app)
            if app.TrafoAC01AStatus == 0
                app.TrafoAC01AStatus = 1;
            else
                app.TrafoAC01AStatus = 0;
            end
            statusTrafoAC01ACheck(app);
        end
        function changeSwitchAC01AStatus(app)
            if app.SwitchAC01AStatus == 0
                app.SwitchAC01AStatus = 1;
            else
                app.SwitchAC01AStatus = 0;
            end
            statusSwitchAC01ACheck(app);
        end

        %Change status STB
        function changeSwitchSTBStatus(app)
            if app.SwitchSTBStatus == 0
                app.SwitchSTBStatus = 1;
            else
                app.SwitchSTBStatus = 0;
            end
            statusSwitchSTBCheck(app);
        end
        function changeTrafoAC01BStatus(app)
            if app.TrafoAC01BStatus == 0
                app.TrafoAC01BStatus = 1;
            else
                app.TrafoAC01BStatus = 0;
            end
            statusTrafoAC01BCheck(app);
        end
        function changeSwitchAC01BStatus(app)
            if app.SwitchAC01BStatus == 0
                app.SwitchAC01BStatus = 1;
            else
                app.SwitchAC01BStatus = 0;
            end
            statusSwitchAC01BCheck(app);
        end
        
        %Change status Bus C01A
        function changeBusC01AStatus(app)
            if app.BusC01AStatus == 0
                app.BusC01AStatus = 1;
            else
                app.BusC01AStatus = 0;
            end
        end
        %Change status Bus C01B
        function changeBusC01BStatus(app)
            if app.BusC01BStatus == 0
                app.BusC01BStatus = 1;
            else
                app.BusC01BStatus = 0;
            end
        end
        
        %Change status Motor K3
        function changeSwitchSMAStatus(app)
            if app.SwitchSMAStatus == 0
                app.SwitchSMAStatus = 1;
            else
                app.SwitchSMAStatus = 0;
            end
            statusSwitchSMACheck(app);
        end
        function changek3Status(app)
            if app.K3Status == 0
                app.K3Status = 1;
            else
                app.K3Status = 0;
            end
            statusk3Check(app);
        end
        %Change status Motor K1
        function changeSwitchSMBStatus(app)
            if app.SwitchSMBStatus == 0
                app.SwitchSMBStatus = 1;
            else
                app.SwitchSMBStatus = 0;
            end
            statusSwitchSMBCheck(app);
        end
        function changek1Status(app)
            if app.K1Status == 0
                app.K1Status = 1;
            else
                app.K1Status = 0;
            end
            statusk1Check(app);
        end
        %Change status Motor K2
        function changeSwitchSMCStatus(app)
            if app.SwitchSMCStatus == 0
                app.SwitchSMCStatus = 1;
            else
                app.SwitchSMCStatus = 0;
            end
            statusSwitchSMCCheck(app);
        end
        function changek2Status(app)
            if app.K2Status == 0
                app.K2Status = 1;
            else
                app.K2Status = 0;
            end
            statusk2Check(app);
        end
        %Change status Motor K4
        function changeSwitchSMDStatus(app)
            if app.SwitchSMDStatus == 0
                app.SwitchSMDStatus = 1;
            else
                app.SwitchSMDStatus = 0;
            end
            statusSwitchSMDCheck(app);
        end
        function changek4Status(app)
            if app.K4Status == 0
                app.K4Status = 1;
            else
                app.K4Status = 0;
            end
            statusk4Check(app);
        end
        
        function changeSwitchC01Status(app)
            if app.SwitchC01Status == 0
                app.SwitchC01Status = 1;
            else
                app.SwitchC01Status = 0;
            end
            statusSwitchC01Check(app);
        end

        %% Value change
        function changeSg01Value(app)
            app.powerSg01Value.Text = int2str(app.sg01_active);
            app.activeSg01Value.Text = int2str(app.sg01_power_rating);
            app.reactiveSg01Value.Text = int2str(app.sg01_reactive);
            app.currentSg01Value.Text = int2str(app.sg01_current);
            app.voltageSg01Value.Text = int2str(app.sg01_voltage_rating);
            app.frequencySg01Value.Text = int2str(app.sg01_frequency_rating);
        end

        function changeSg02Value(app)
            app.powerSg02Value.Text = int2str(app.sg02_active);
            app.activeSg02Value.Text = int2str(app.sg02_power_rating);
            app.reactiveSg02Value.Text = int2str(app.sg02_reactive);
            app.currentSg02Value.Text = int2str(app.sg02_current);
            app.voltageSg02Value.Text = int2str(app.sg02_voltage_rating);
            app.frequencySg02Value.Text = int2str(app.sg02_frequency_rating);
        end

        function changeSg03Value(app)
            app.powerSg03Value.Text = int2str(app.sg03_active);
            app.activeSg03Value.Text = int2str(app.sg03_power_rating);
            app.reactiveSg03Value.Text = int2str(app.sg03_reactive);
            app.currentSg03Value.Text = int2str(app.sg03_current);
            app.voltageSg03Value.Text = int2str(app.sg03_voltage_rating);
            app.frequencySg03Value.Text = int2str(app.sg03_frequency_rating);
        end

        function changeGt01Value(app)
            app.powerGt01Value.Text = int2str(app.gt01_active);
            app.activeGt01Value.Text = int2str(app.gt01_power_rating);
            app.reactiveGt01Value.Text = int2str(app.gt01_reactive);
            app.currentGt01Value.Text = int2str(app.gt01_current);
            app.voltageGt01Value.Text = int2str(app.gt01_voltage_rating);
            app.frequencyGt01Value.Text = int2str(app.gt01_frequency_rating);
        end

        function changeTrAc01AValue(app)
            app.powerTrAc01AValue.Text = int2str(app.trac01a_active);
            app.reactiveTrAc01AValue.Text = int2str(app.trac01a_reactive);
            app.currentTrAc01AValue.Text = int2str(app.trac01a_current);
            app.voltageTrAc01AValue.Text = int2str(app.trac01a_voltage_rating);
        end

        function changeTrAc01BValue(app)
            app.powerTrAc01BValue.Text = int2str(app.trac01b_active);
            app.reactiveTrAc01BValue.Text = int2str(app.trac01b_reactive);
            app.currentTrAc01BValue.Text = int2str(app.trac01b_current);
            app.voltageTrAc01BValue.Text = int2str(app.trac01b_voltage_rating);
        end

        function changeC01AValue(app)
            app.voltageC01AValue.Text = int2str(app.busc01a_voltage_rating);
            app.frequencyC01AValue.Text = int2str(app.busc01a_frequency);
        end

        function changeC01BValue(app)
            app.voltageC01BValue.Text = int2str(app.busc01b_voltage_rating);
            app.frequencyC01BValue.Text = int2str(app.busc01b_frequency);
        end

       
        %% Logic
        
        %condition change
        function change_condition_state(app)
            if app.condition_1 == 1
                app.condition_1_label.BackgroundColor = 'g';
            else
                app.condition_1_label.BackgroundColor = 'r';
            end

            if app.condition_2 == 1
                app.condition_2_label.BackgroundColor = 'g';
            else
                app.condition_2_label.BackgroundColor = 'r';
            end

            if app.condition_3 == 1
                app.condition_3_label.BackgroundColor = 'g';
            else
                app.condition_3_label.BackgroundColor = 'r';
            end
        end

        %Bus Bar A02
        function busA02Energize(app)
            if (app.Sg01Status == 1 && app.SwitchSg01Status == 1) || (app.Sg02Status == 1 && app.SwitchSg02Status ==1)
               app.BusA02Status = 1;
            else
               app.BusA02Status = 0;
            end
            
            statusBusA02Check(app);
        end

        %Bus Bar A03
        function busA03Energize(app)
            if (app.Sg03Status == 1 && app.SwitchSg03Status == 1) || (app.Sg04Status == 1 && app.SwitchSg04Status ==1)
               app.BusA03Status = 1;
            else
               app.BusA03Status = 0;
            end
            
            statusBusA03Check(app);
        end

        %Bus Bar C01A
        function busC01AEnergize(app)
            if (app.SwitchSTAStatus == 1 && app.BusA02Status == 1 && app.SwitchAC01AStatus == 1) 
               app.BusC01AStatus = 1;
            else
               app.BusC01AStatus = 0;
            end
            
            statusBusC01ACheck(app);
        end

        %Bus Bar C01B
        function busC01BEnergize(app)
            if (app.SwitchSTBStatus == 1 && app.BusA03Status == 1 && app.SwitchAC01BStatus == 1) 
               app.BusC01BStatus = 1;
            else
               app.BusC01BStatus = 0;
            end
            
            statusBusC01BCheck(app);
        end

        %Trafo AC01A
        function trafoAC01AEnergize(app)
            if (app.SwitchSTAStatus == 1 && app.BusA02Status == 1) 
               app.TrafoAC01AStatus = 1;
            else
               app.TrafoAC01AStatus = 0;
            end
            
            statusTrafoAC01ACheck(app);
        end

        %Trafo AC01B
        function trafoAC01BEnergize(app)
            if (app.SwitchSTBStatus == 1 && app.BusA03Status == 1) 
               app.TrafoAC01BStatus = 1;
            else
               app.TrafoAC01BStatus = 0;
            end
            
            statusTrafoAC01BCheck(app);
        end

        %Motor K3
        function motorK3Run(app)
            if app.BusC01AStatus == 1 && app.SwitchSMAStatus == 1
               app.K3Status = 1;
            else
               app.K3Status = 0;
            end
            
            statusk3Check(app);
        end

        %Motor K1
        function motorK1Run(app)
            if app.BusC01AStatus == 1 && app.SwitchSMBStatus == 1
               app.K1Status = 1;
            else
               app.K1Status = 0;
            end
            
            statusk1Check(app);
        end

        %Motor K4
        function motorK2Run(app)
            if app.BusC01BStatus == 1 && app.SwitchSMCStatus == 1
               app.K2Status = 1;
            else
               app.K2Status = 0;
            end
            
            statusk2Check(app);
        end

        %Motor K2
        function motorK4Run(app)
            if  app.BusC01BStatus == 1 && app.SwitchSMDStatus == 1
               app.K4Status = 1;
            else
               app.K4Status = 0;
            end
            
            statusk4Check(app);
        end
        function busC01Distributed(app)
            if (app.BusA02Status == 1 && app.SwitchSTAStatus == 1 && app.SwitchAC01AStatus == 1) && (app.BusA03Status == 0 || app.SwitchSTBStatus == 0 || app.SwitchAC01BStatus == 0)
                if app.SwitchC01Status == 1
                    app.BusC01BStatus = 1;
                else
                    app.BusC01BStatus = 0;
                end
            elseif (app.BusA03Status == 1 && app.SwitchSTBStatus == 1 && app.SwitchAC01BStatus == 1) && (app.BusA02Status == 0 || app.SwitchSTAStatus == 0 || app.SwitchAC01AStatus == 0)
                if app.SwitchC01Status == 1
                    app.BusC01AStatus = 1;
                else
                    app.BusC01AStatus = 0;
                end
            elseif (app.BusA03Status == 1 && app.SwitchSTBStatus == 1 && app.SwitchAC01BStatus == 1) && (app.BusA02Status == 1 && app.SwitchSTAStatus == 1 && app.SwitchAC01AStatus == 1)
                
            else
                app.BusC01AStatus = 0;
                app.BusC01BStatus = 0;
            end

            statusBusC01ACheck(app);
            statusBusC01BCheck(app);
        end

        function busC01DefaultActive_check(app)
            if app.SwitchAC01AStatus == 1 || app.SwitchAC01BStatus == 1
                app.BusC01AStatus = 1;
                app.BusC01BStatus = 1;
            end
            statusSwitchC01Check(app);
            statusBusC01ACheck(app);
            statusBusC01BCheck(app);
            
        end
        
        function system_A_check(app)
            busA02Energize(app);
            trafoAC01AEnergize(app);
            statusSwitchAC01ACheck(app);
            %busC01Distributed(app);
            motorK1Run(app);
            motorK3Run(app);
        end

         function system_B_check(app)
            busA03Energize(app);
            trafoAC01BEnergize(app);
            statusSwitchAC01BCheck(app);
            %busC01Distributed(app);
            motorK2Run(app);
            motorK4Run(app);
        end
        
        %ats switching
        function ats_running(app)
            if app.SwitchC01Status == 0
                busA02_load_calculate(app);
                busC01A_load_calculate(app);
                busA03_load_calculate(app);
                busC01B_load_calculate(app);
                if app.trac01a_load >=60
                    app.SwitchAC01AStatus = 0;
                    
                    system_A_check(app);
                end
    
                if app.trac01b_load >=60
                    app.SwitchAC01BStatus = 0;
                    
                    system_B_check(app);
                end
                busC01Distributed(app);
            else
                if app.TrafoAC01AStatus == 1 && app.TrafoAC01BStatus == 1
                    if app.SwitchAC01AStatus == 0 && app.SwitchAC01BStatus==0
                        if app.SwitchSMAStatus == 1 || app.SwitchSMBStatus == 1 || app.SwitchSMCStatus == 1 || app.SwitchSMDStatus == 1
                            if (app.SwitchSMAStatus == 1 || app.SwitchSMBStatus == 1) && (app.SwitchSMCStatus == 0 || app.SwitchSMDStatus == 0)
                                app.SwitchAC01AStatus = 1;
                            elseif (app.SwitchSMAStatus == 0 || app.SwitchSMBStatus == 0) && (app.SwitchSMCStatus == 1 || app.SwitchSMDStatus == 1)
                                app.SwitchAC01BStatus = 1;
                            else
                                app.SwitchAC01AStatus = 1;
                            end
                        end
                    end
                elseif app.TrafoAC01AStatus == 1 && app.TrafoAC01BStatus == 0
                    if app.SwitchAC01AStatus == 0
                        if app.SwitchSMAStatus == 1 || app.SwitchSMBStatus == 1 || app.SwitchSMCStatus == 1 || app.SwitchSMDStatus == 1
                            app.SwitchAC01AStatus = 1;
                        end
                    end
                end
                
                busC01DefaultActive_check(app);
                system_A_check(app);
                system_B_check(app);
                busA02_load_calculate(app);
                busA03_load_calculate(app);
                busC01_load_calculate(app);

                if app.trac01a_load >=60 && app.trac01b_load ==0 && app.SwitchAC01AStatus == 1 && app.SwitchAC01BStatus == 0
                    if app.TrafoAC01BStatus == 0
                        app.SwitchAC01AStatus = 0;
                        app.BusC01AStatus =  0;
                        app.BusC01BStatus =  0;
                    else
                        app.SwitchAC01BStatus = 1;
                    end
                    system_A_check(app);
                    system_B_check(app);
                    statusBusC01ACheck(app);
                    statusBusC01BCheck(app);
                elseif app.trac01a_load == 0 && app.trac01b_load >= 60 && app.SwitchAC01AStatus == 0 && app.SwitchAC01BStatus == 1
                    if app.TrafoAC01AStatus == 0
                        app.SwitchAC01BStatus = 0;
                        app.BusC01AStatus =  0;
                        app.BusC01BStatus =  0;
                    else
                        app.SwitchAC01AStatus = 1;
                    end
                   
                    system_A_check(app);
                    system_B_check(app);
                    statusBusC01ACheck(app);
                    statusBusC01BCheck(app);
                elseif app.trac01a_load >=60 && app.trac01b_load >=60 && app.SwitchAC01AStatus == 1 && app.SwitchAC01BStatus == 1
                    app.SwitchAC01AStatus = 0;
                    app.SwitchAC01BStatus = 0;
                    app.BusC01AStatus =  0;
                    app.BusC01BStatus =  0;
                    
                    system_A_check(app);
                    system_B_check(app);
                    statusBusC01ACheck(app);
                    statusBusC01BCheck(app);
                
                end

                
            end
        end

        %% calculation
        function runpowerflow(app)
            %run psat script
            main_script;
            %scriptName('system_back_end.m');

            %set parameter
            sg01_setparams(app);
            sg02_setparams(app);
            sg03_setparams(app);
            gt01_setparams(app);
            trasg01_setparams(app);
            trasg02_setparams(app);
            trasg03_setparams(app);
            tragt01_setparams(app);
            trac01a_setparams(app);
            trac01b_setparams(app);
            k1m_setparams(app);
            k2m_setparams(app);
            k3m_setparams(app);
            k4m_setparams(app);
            busa02_setparams(app);
            busa03_setparams(app);
            busc01a_setparams(app);


            %get value from psat
            sg01_powerflow(app,gen_power_rating(2),gen_voltage_rating(2),gen_frequency_rating(2));
            sg02_powerflow(app,gen_power_rating(3),gen_voltage_rating(3),gen_frequency_rating(3));
            sg03_powerflow(app,gen_power_rating(4),gen_voltage_rating(4),gen_frequency_rating(4));
            gt01_powerflow(app,gen_power_rating(1),gen_voltage_rating(1),gen_frequency_rating(1));
 
            %app.sg01_active = gen_power(2);
            %app.sg02_active = gen_power(3);
            %app.sg03_active = gen_power(4);
            %app.gt01_active = gen_power(1);
            app.sg01_active = bus_active_power_generated(4);
            app.sg02_active = bus_active_power_generated(5);
            app.sg03_active = bus_active_power_generated(6);
            app.gt01_active = bus_active_power_generated(7);
            app.busa02_active_gen = bus_active_power_generated(1);
            app.busa03_active_gen = bus_active_power_generated(2);
            app.busc01a_active_gen = bus_active_power_generated(3);
            app.busc01b_active_gen = bus_active_power_generated(3);
            app.busa02_active_load = bus_active_power_load(1);
            app.busa03_active_load = bus_active_power_load(2);
            app.busc01a_active_load = bus_active_power_load(3);
            app.busc01b_active_load = bus_active_power_load(3);
            app.busa02_reactive_gen = bus_reactive_power_generated(1);
            app.busa03_reactive_gen = bus_reactive_power_generated(2);
            app.busc01a_reactive_gen = bus_reactive_power_generated(3);
            app.busc01b_reactive_gen = bus_reactive_power_generated(3);
            app.busa02_reactive_load = bus_reactive_power_load(1);
            app.busa03_reactive_load = bus_reactive_power_load(2);
            app.busc01a_reactive_load = bus_reactive_power_load(3);
            app.busc01b_reactive_load = bus_reactive_power_load(3);
            app.busa02_voltage_rating = bus_voltage_rating(1);
            app.busa03_voltage_rating = bus_voltage_rating(2);
            app.busc01a_voltage_rating = bus_voltage_rating(3);
            app.busc01b_voltage_rating = bus_voltage_rating(3);
            app.trasg01_voltage_from = trafo_voltage_from(3);
            app.trasg02_voltage_from = trafo_voltage_from(4);
            app.trasg03_voltage_from = trafo_voltage_from(5);
            app.tragt01_voltage_from = trafo_voltage_from(6); 
            app.trac01a_voltage_from = trafo_voltage_from(1); 
            app.trac01b_voltage_from = trafo_voltage_from(2);
            app.trasg01_voltage_to = trafo_voltage_to(3);
            app.trasg02_voltage_to = trafo_voltage_to(4);
            app.trasg03_voltage_to = trafo_voltage_to(5);
            app.tragt01_voltage_to = trafo_voltage_to(6); 
            app.trac01a_voltage_to = trafo_voltage_to(1); 
            app.trac01b_voltage_to = trafo_voltage_to(2);
            app.k1_active =bus_active_power_generated(8);
            app.k2_active =bus_active_power_generated(9);
            app.k3_active =bus_active_power_generated(10);
            app.k4_active =bus_active_power_generated(11);
            changeSg01Value(app);
            changeSg02Value(app);
            changeSg03Value(app);
            changeGt01Value(app);
            changeTrAc01AValue(app);
            changeTrAc01BValue(app);
            changeC01AValue(app);
            changeC01BValue(app);
        end
        
        function busA02_load_calculate(app)
            app.trasg01_active = app.sg01_active * app.Sg01Status;
            app.trasg02_active = app.sg02_active * app.Sg02Status;
            if (app.trasg01_active > 0 || app.trasg02_active>0) && app.BusA02Status
                %calculate connected bus a02                
                app.busa02_active_gen = (app.trasg01_active * app.SwitchSg01Status)+(app.trasg02_active * app.SwitchSg02Status);
                app.busa02_active_load = app.busc01a_active_load;
                total_load = app.busa02_active_gen + app.busa02_active_load;
                total_generated =(app.TrafoSg01Status*app.trasg01_active) + (app.TrafoSg02Status*app.trasg02_active);
                app.trasg01_load = app.TrafoSg01Status*(( total_generated - total_load)/(total_generated)*100); 
                app.trasg02_load = app.TrafoSg02Status*(( total_generated - total_load)/(total_generated)*100); 
                app.busa02_load = (app.trasg01_load+app.trasg02_load)/(app.TrafoSg01Status + app.TrafoSg02Status);

                %value check
                changeSg01Value(app);
                changeSg02Value(app);
            end
        end

        function busA03_load_calculate(app)
            app.trasg03_active = app.sg03_active * app.Sg03Status;
            app.tragt01_active = app.gt01_active * app.Sg04Status;
            if (app.trasg03_active > 0 || app.tragt01_active>0) && app.BusA03Status
                %calculate connected bus a02     
                app.busa03_active_gen = (app.trasg03_active * app.SwitchSg03Status)+(app.tragt01_active * app.SwitchSg04Status);
                app.busa03_active_load = app.busc01b_active_load;
                total_load = app.busa03_active_gen + app.busa03_active_load;
                total_generated =(app.TrafoSg03Status*app.trasg03_active) + (app.TrafoSg04Status*app.tragt01_active);
                app.trasg03_load = app.TrafoSg03Status*(( total_generated - total_load)/(total_generated)*100); 
                app.tragt01_load = app.TrafoSg04Status*(( total_generated - total_load)/(total_generated)*100); 
                app.busa03_load = (app.trasg03_load+app.tragt01_load)/(app.TrafoSg03Status + app.TrafoSg04Status);

                %value check
                changeSg03Value(app);
                changeGt01Value(app);
            end
        end

        function busC01A_load_calculate(app)
            app.trac01a_active = app.busa02_active_gen * app.TrafoAC01AStatus;
            if app.trac01a_active > 0
                %calculate connected to bus c01a
                app.busc01a_active_gen = app.trac01a_active * app.TrafoAC01AStatus;
                app.busc01a_active_load = -((app.k1_active * app.K1Status) + (app.k3_active * app.K3Status));
                total_load = app.busc01a_active_gen + app.busc01a_active_load;
                app.trac01a_load = ((app.trac01a_active - total_load)/app.trac01a_active)*100; 
                app.busc01a_load = app.trac01a_load;
                
                %value check
                changeTrAc01AValue(app);
                changeC01AValue(app);
            end
            
        end

        function busC01B_load_calculate(app)
            app.trac01b_active = app.busa03_active_gen * app.TrafoAC01BStatus;
            if app.trac01b_active > 0
                %calculate connected to bus c01b
                app.busc01b_active_gen = app.trac01b_active * app.TrafoAC01BStatus;
                app.busc01b_active_load = -((app.k2_active * app.K2Status) + (app.k4_active * app.K4Status));
                total_load = app.busc01b_active_gen + app.busc01b_active_load;
                app.trac01b_load = ((app.trac01b_active - total_load)/app.trac01b_active)*100; 
                app.busc01b_load = app.trac01b_load;
                
                %value check
                changeTrAc01BValue(app);
                changeC01BValue(app);
            end
            
        end

        function busC01_load_calculate(app)
            app.trac01a_active = app.busa02_active_gen * app.TrafoAC01AStatus;
            app.trac01b_active = app.busa03_active_gen * app.TrafoAC01BStatus;

            if app.trac01a_active > 0 && app.trac01b_active == 0
                %calculate connected to bus c01b
                app.busc01a_active_gen = app.trac01a_active * app.TrafoAC01AStatus;
                app.busc01a_active_load = -((app.k1_active * app.K1Status) + (app.k3_active * app.K3Status)+(app.k2_active * app.K2Status) + (app.k4_active * app.K4Status));
                total_load = app.busc01a_active_gen + app.busc01a_active_load;
                app.trac01a_load = ((app.trac01a_active - total_load)/app.trac01a_active)*100; 
                app.busc01a_load = app.trac01a_load;
                app.busc01b_load = app.trac01a_load;
            elseif app.trac01a_active == 0 && app.trac01b_active > 0
                %calculate connected to bus c01a
                app.busc01b_active_gen = app.trac01b_active * app.TrafoAC01BStatus;
                app.busc01b_active_load = -((app.k2_active * app.K2Status) + (app.k4_active * app.K4Status)+(app.k2_active * app.K2Status) + (app.k4_active * app.K4Status));
                total_load = app.busc01b_active_gen + app.busc01b_active_load;
                app.trac01b_load = ((app.trac01b_active - total_load)/app.trac01b_active)*100; 
                app.busc01a_load = app.trac01b_load;
                app.busc01b_load = app.trac01b_load;
            else
                app.busc01a_active_gen = (app.SwitchAC01AStatus*(app.trac01a_active * app.TrafoAC01AStatus)) + (app.SwitchAC01BStatus*(app.trac01b_active * app.TrafoAC01BStatus));
                app.busc01b_active_gen = (app.SwitchAC01AStatus*(app.trac01a_active * app.TrafoAC01AStatus)) + (app.SwitchAC01BStatus*(app.trac01b_active * app.TrafoAC01BStatus));
                app.busc01a_active_load = -(((app.k1_active * app.K1Status) + (app.k3_active * app.K3Status)+(app.k2_active * app.K2Status) + (app.k4_active * app.K4Status)))/(app.SwitchAC01AStatus+app.SwitchAC01BStatus);
                app.busc01b_active_load = -(((app.k1_active * app.K1Status) + (app.k3_active * app.K3Status)+(app.k2_active * app.K2Status) + (app.k4_active * app.K4Status)))/(app.SwitchAC01AStatus+app.SwitchAC01BStatus);
                total_load =app.busc01a_active_gen + app.busc01a_active_load ;
                total_generated =(app.SwitchAC01AStatus*app.trac01a_active)+(app.SwitchAC01BStatus*app.trac01b_active);
                app.trac01a_load = (((total_generated - total_load)/total_generated))*100*app.SwitchAC01AStatus; 
                app.trac01b_load = (((total_generated - total_load)/total_generated)*100)*app.SwitchAC01BStatus; 
                app.busc01a_load = app.trac01a_load;
                app.busc01b_load = app.trac01b_load;
            end
            %value check
            changeTrAc01AValue(app);
            changeC01AValue(app);
            changeTrAc01BValue(app);
            changeC01BValue(app);
        end
        %% Set params function
        % default from simulink
        function default_variable(app)
            %load simulink model
            load_system('system_back_end.mdl');
            
            %generator
            sg01_block = 'system_back_end/STG A-331-03A-SG01';
            sg02_block ='system_back_end/STG A-331-03A-SG02';
            sg03_block ='system_back_end/STG A-331-03A-SG03';
            gt01_block ='system_back_end/GTG A-331-07A-GT01';

            %transformer
            trafo_sg01_block ='system_back_end/101-TR-BA01';
            trafo_sg02_block ='system_back_end/101-TR-BA02';
            trafo_sg03_block ='system_back_end/101-TR-BA03';
            trafo_gt01_block ='system_back_end/101-TR-BA04';
            trafo_ac01a_block ='system_back_end/101-TR-AC01A';
            trafo_ac01b_block ='system_back_end/101-TR-AC01B';

            %motor
            motor_k1_block ='system_back_end/A-332-01-K1-M';
            motor_k2_block ='system_back_end/A-332-01-K2-M';
            motor_k3_block ='system_back_end/A-332-01-K3-M';
            motor_k4_block ='system_back_end/A-332-01-K4-M';

            %bus
            bus_a02_block ='system_back_end/101-HSW-A02';
            bus_a03_block ='system_back_end/101-HSW-A03';
            bus_c01_block ='system_back_end/101-HSW-C01A&B';

            %get generator default value
            sg01_ratings = str2num(get_param(sg01_block,'p2_3_4q'));
            sg02_ratings = str2num(get_param(sg02_block,'p2_3_4q'));
            sg03_ratings = str2num(get_param(sg03_block,'p2_3_4q'));
            gt01_ratings = str2num(get_param(gt01_block,'p2_3_4q'));
            app.sg01_power_rating = sg01_ratings(1);
            app.sg02_power_rating = sg02_ratings(1);
            app.sg03_power_rating = sg03_ratings(1);
            app.gt01_power_rating = gt01_ratings(1);
            app.sg01_voltage_rating = sg01_ratings(2);
            app.sg02_voltage_rating = sg02_ratings(2);
            app.sg03_voltage_rating = sg03_ratings(2);
            app.gt01_voltage_rating = gt01_ratings(2);
            app.sg01_frequency_rating = sg01_ratings(3);
            app.sg02_frequency_rating = sg02_ratings(3);
            app.sg03_frequency_rating = sg03_ratings(3);
            app.gt01_frequency_rating = gt01_ratings(3);

            %get transformer data
            trafo_sg01_ratings = str2num(get_param(trafo_sg01_block,'p3_4_5q'));
            trafo_sg02_ratings = str2num(get_param(trafo_sg02_block,'p3_4_5q'));
            trafo_sg03_ratings = str2num(get_param(trafo_sg03_block,'p3_4_5q'));
            trafo_gt01_ratings = str2num(get_param(trafo_gt01_block,'p3_4_5q'));
            trafo_ac01a_ratings = str2num(get_param(trafo_ac01a_block,'p3_4_5q'));
            trafo_ac01b_ratings = str2num(get_param(trafo_ac01b_block,'p3_4_5q'));
            trafo_sg01_voltage_conversion = sscanf(get_param(trafo_sg01_block,'p7q'),'%f/%f');
            trafo_sg02_voltage_conversion = sscanf(get_param(trafo_sg02_block,'p7q'),'%f/%f');
            trafo_sg03_voltage_conversion = sscanf(get_param(trafo_sg03_block,'p7q'),'%f/%f');
            trafo_gt01_voltage_conversion = sscanf(get_param(trafo_gt01_block,'p7q'),'%f/%f');
            trafo_ac01a_voltage_conversion = sscanf(get_param(trafo_ac01a_block,'p7q'),'%f/%f');
            trafo_ac01b_voltage_conversion = sscanf(get_param(trafo_ac01b_block,'p7q'),'%f/%f');
            app.trasg01_power_rating =trafo_sg01_ratings(1);
            app.trasg02_power_rating =trafo_sg02_ratings(1);
            app.trasg03_power_rating =trafo_sg03_ratings(1);
            app.tragt01_power_rating =trafo_gt01_ratings(1);
            app.trac01a_power_rating =trafo_ac01a_ratings(1);
            app.trac01b_power_rating =trafo_ac01b_ratings(1);
            app.trasg01_voltage_rating =trafo_sg01_ratings(2);
            app.trasg02_voltage_rating =trafo_sg02_ratings(2);
            app.trasg03_voltage_rating =trafo_sg03_ratings(2);
            app.tragt01_voltage_rating =trafo_gt01_ratings(2);
            app.trac01a_voltage_rating =trafo_ac01a_ratings(2);
            app.trac01b_voltage_rating =trafo_ac01b_ratings(2);
            app.trasg01_frequency_rating =trafo_sg01_ratings(3);
            app.trasg02_frequency_rating =trafo_sg02_ratings(3);
            app.trasg03_frequency_rating =trafo_sg03_ratings(3);
            app.tragt01_frequency_rating =trafo_gt01_ratings(3);
            app.trac01a_frequency_rating =trafo_ac01a_ratings(3);
            app.trac01b_frequency_rating =trafo_ac01b_ratings(3);
            app.trasg01_voltage_from = trafo_sg01_voltage_conversion (1);
            app.trasg01_voltage_to = trafo_sg01_voltage_conversion (2);
            app.trasg02_voltage_from = trafo_sg02_voltage_conversion (1);
            app.trasg02_voltage_to = trafo_sg02_voltage_conversion (2);
            app.trasg03_voltage_from = trafo_sg03_voltage_conversion (1);
            app.trasg03_voltage_to = trafo_sg03_voltage_conversion (2);
            app.tragt01_voltage_from = trafo_gt01_voltage_conversion (1);
            app.tragt01_voltage_to = trafo_gt01_voltage_conversion (2); 
            app.trac01a_voltage_from = trafo_ac01a_voltage_conversion (1);
            app.trac01a_voltage_to = trafo_ac01a_voltage_conversion (2);
            app.trac01b_voltage_from = trafo_ac01b_voltage_conversion (1);
            app.trac01b_voltage_to = trafo_ac01b_voltage_conversion (2); 

            %get motor data
            motor_k1_ratings = str2num(get_param(motor_k1_block,'p2_3_4q'));
            motor_k2_ratings = str2num(get_param(motor_k2_block,'p2_3_4q'));
            motor_k3_ratings = str2num(get_param(motor_k3_block,'p2_3_4q'));
            motor_k4_ratings = str2num(get_param(motor_k4_block,'p2_3_4q'));
            app.k1_power_rating = motor_k1_ratings(1);
            app.k2_power_rating = motor_k2_ratings(1);
            app.k3_power_rating = motor_k3_ratings(1);
            app.k4_power_rating = motor_k4_ratings(1);
            app.k1_voltage_rating = motor_k1_ratings(2);
            app.k2_voltage_rating = motor_k2_ratings(2);
            app.k3_voltage_rating = motor_k3_ratings(2);
            app.k4_voltage_rating = motor_k4_ratings(2);
            app.k1_frequency_rating = motor_k1_ratings(3);
            app.k2_frequency_rating = motor_k2_ratings(3);
            app.k3_frequency_rating = motor_k3_ratings(3);
            app.k4_frequency_rating = motor_k4_ratings(3);

            %get bus data
            app.busa02_voltage_rating = str2num(get_param(bus_a02_block,'p2q'));
            app.busa03_voltage_rating = str2num(get_param(bus_a03_block,'p2q'));
            app.busc01a_voltage_rating = str2num(get_param(bus_c01_block,'p2q'));
            app.busc01b_voltage_rating = str2num(get_param(bus_c01_block,'p2q'));

        end

        % generator
        function sg01_setparams(app)
            s_rating = app.sg01_power_rating/1000;
            v_rating = app.sg01_voltage_rating;
            f_rating = app.sg01_frequency_rating;
            block ='system_back_end/STG A-331-03A-SG01';
            block_mat = [s_rating,v_rating,f_rating];
            block_char = mat2str(block_mat);
            set_param(block,'p2_3_4q',block_char);
        end

        function sg02_setparams(app)
            s_rating = app.sg02_power_rating/1000;
            v_rating = app.sg02_voltage_rating;
            f_rating = app.sg02_frequency_rating;
            block ='system_back_end/STG A-331-03A-SG02';
            block_mat = [s_rating,v_rating,f_rating];
            block_char = mat2str(block_mat);
            set_param(block,'p2_3_4q',block_char);
        end

        function sg03_setparams(app)
            s_rating = app.sg03_power_rating/1000;
            v_rating = app.sg03_voltage_rating;
            f_rating = app.sg03_frequency_rating;
            block ='system_back_end/STG A-331-03A-SG03';
            block_mat = [s_rating,v_rating,f_rating];
            block_char = mat2str(block_mat);
            set_param(block,'p2_3_4q',block_char);
        end

        function gt01_setparams(app)
            s_rating = app.gt01_power_rating/1000;
            v_rating = app.gt01_voltage_rating;
            f_rating = app.gt01_frequency_rating;
            block ='system_back_end/GTG A-331-07A-GT01';
            block_mat = [s_rating,v_rating,f_rating];
            block_char = mat2str(block_mat);
            set_param(block,'p2_3_4q',block_char);
        end

        %transformer
        function trasg01_setparams(app)
            s_rating = app.trasg01_power_rating/1000;
            v_rating = app.trasg01_voltage_rating;
            f_rating = app.trasg01_frequency_rating;
            v_conv = strcat(int2str(app.trasg01_voltage_from),'/',int2str(app.trasg01_voltage_to));
            block ='system_back_end/101-TR-BA01';
            block_mat_rating = [s_rating,v_rating,f_rating];
            block_char = mat2str(block_mat_rating);
            set_param(block,'p3_4_5q',block_char);
            set_param(block,'p7q',v_conv);
        end

        function trasg02_setparams(app)
            s_rating = app.trasg02_power_rating/1000;
            v_rating = app.trasg02_voltage_rating;
            f_rating = app.trasg02_frequency_rating;
            v_conv = strcat(int2str(app.trasg02_voltage_from),'/',int2str(app.trasg02_voltage_to));
            block ='system_back_end/101-TR-BA02';
            block_mat_rating = [s_rating,v_rating,f_rating];
            block_char = mat2str(block_mat_rating);
            set_param(block,'p3_4_5q',block_char);
            set_param(block,'p7q',v_conv);
        end

        function trasg03_setparams(app)
            s_rating = app.trasg03_power_rating/1000;
            v_rating = app.trasg03_voltage_rating;
            f_rating = app.trasg03_frequency_rating;
            v_conv = strcat(int2str(app.trasg03_voltage_from),'/',int2str(app.trasg03_voltage_to));
            block ='system_back_end/101-TR-BA03';
            block_mat_rating = [s_rating,v_rating,f_rating];
            block_char = mat2str(block_mat_rating);
            set_param(block,'p3_4_5q',block_char);
            set_param(block,'p7q',v_conv);
        end

        function tragt01_setparams(app)
            s_rating = app.tragt01_power_rating/1000;
            v_rating = app.tragt01_voltage_rating;
            f_rating = app.tragt01_frequency_rating;
            v_conv = strcat(int2str(app.tragt01_voltage_from),'/',int2str(app.tragt01_voltage_to));
            block ='system_back_end/101-TR-BA04';
            block_mat_rating = [s_rating,v_rating,f_rating];
            block_char = mat2str(block_mat_rating);
            set_param(block,'p3_4_5q',block_char);
            set_param(block,'p7q',v_conv);
        end

        function trac01a_setparams(app)
            s_rating = app.trac01a_power_rating/1000;
            v_rating = app.trac01a_voltage_rating;
            f_rating = app.trac01a_frequency_rating;
            v_conv = strcat(int2str(app.trac01a_voltage_from),'/',int2str(app.trac01a_voltage_to));
            block ='system_back_end/101-TR-AC01A';
            block_mat_rating = [s_rating,v_rating,f_rating];
            block_char = mat2str(block_mat_rating);
            set_param(block,'p3_4_5q',block_char);
            set_param(block,'p7q',v_conv);
        end

        function trac01b_setparams(app)
            s_rating = app.trac01b_power_rating/1000;
            v_rating = app.trac01b_voltage_rating;
            f_rating = app.trac01b_frequency_rating;
            v_conv = strcat(int2str(app.trac01b_voltage_from),'/',int2str(app.trac01b_voltage_to));
            block ='system_back_end/101-TR-AC01B';
            block_mat_rating = [s_rating,v_rating,f_rating];
            block_char = mat2str(block_mat_rating);
            set_param(block,'p3_4_5q',block_char);
            set_param(block,'p7q',v_conv);
        end
        
        %motors
        function k1m_setparams(app)
            s_rating = app.k1_power_rating/1000;
            v_rating = app.k1_voltage_rating;
            f_rating = app.k1_frequency_rating;
            block ='system_back_end/A-332-01-K1-M';
            block_mat = [s_rating,v_rating,f_rating];
            block_char = mat2str(block_mat);
            set_param(block,'p2_3_4q',block_char);
        end

        function k2m_setparams(app)
            s_rating = app.k2_power_rating/1000;
            v_rating = app.k2_voltage_rating;
            f_rating = app.k2_frequency_rating;
            block ='system_back_end/A-332-01-K2-M';
            block_mat = [s_rating,v_rating,f_rating];
            block_char = mat2str(block_mat);
            set_param(block,'p2_3_4q',block_char);
        end

        function k3m_setparams(app)
            s_rating = app.k3_power_rating/1000;
            v_rating = app.k3_voltage_rating;
            f_rating = app.k3_frequency_rating;
            block ='system_back_end/A-332-01-K3-M';
            block_mat = [s_rating,v_rating,f_rating];
            block_char = mat2str(block_mat);
            set_param(block,'p2_3_4q',block_char);
        end

        function k4m_setparams(app)
            s_rating = app.k4_power_rating/1000;
            v_rating = app.k4_voltage_rating;
            f_rating = app.k4_frequency_rating;
            block ='system_back_end/A-332-01-K4-M';
            block_mat = [s_rating,v_rating,f_rating];
            block_char = mat2str(block_mat);
            set_param(block,'p2_3_4q',block_char);
        end

        %bus
        function busa02_setparams(app)
            v_rating = app.busa02_voltage_rating;
            block ='system_back_end/101-HSW-A02';
            block_mat = int2str(v_rating);
            set_param(block,'p2q',block_mat);
        end

        function busa03_setparams(app)
            v_rating = app.busa03_voltage_rating;
            block ='system_back_end/101-HSW-A03';
            block_mat = int2str(v_rating);
            set_param(block,'p2q',block_mat);
        end

        function busc01a_setparams(app)
            v_rating = app.busc01a_voltage_rating;
            block ='system_back_end/101-HSW-C01A&B';
            block_mat = int2str(v_rating);
            set_param(block,'p2q',block_mat);
        end
        
        %% Update variable function
        %generator
        function sg01_powerflow(app,power_rating,voltage_rating,frequency_rating)
            app.sg01_power_rating = power_rating;
            app.sg01_voltage_rating = voltage_rating;
            app.sg01_frequency_rating = frequency_rating;
        end

        function sg02_powerflow(app,power_rating,voltage_rating,frequency_rating)
            app.sg02_power_rating = power_rating;
            app.sg02_voltage_rating = voltage_rating;
            app.sg02_frequency_rating = frequency_rating;
        end

        function sg03_powerflow(app,power_rating,voltage_rating,frequency_rating)
            app.sg03_power_rating = power_rating;
            app.sg03_voltage_rating = voltage_rating;
            app.sg03_frequency_rating = frequency_rating;
        end

        function gt01_powerflow(app,power_rating,voltage_rating,frequency_rating)
            app.gt01_power_rating = power_rating;
            app.gt01_voltage_rating = voltage_rating;
            app.gt01_frequency_rating = frequency_rating;
        end
    end
    
    methods (Access = public)
        
        function importdata(app,condition_1,condition_2,condition_3)
            app.condition_1 = condition_1;
            app.condition_2 = condition_2;
            app.condition_3 = condition_3;
            change_condition_state(app);
        end

        %% generator
        function importdata_gen_sg01(app,s_rating, v_rating, f_rating, l, i, p, q)
            app.sg01_power_rating = s_rating/1000;
            app.sg01_voltage_rating = v_rating;
            app.sg01_frequency_rating = f_rating;
            app.sg01_load = l;
            app.sg01_current = i;
            app.sg01_active = p;
            app.sg01_reactive = q;
            changeSg01Value(app);
        end
        
        function importdata_gen_sg02(app,s_rating, v_rating, f_rating, l, i, p, q)
            app.sg02_power_rating = s_rating/1000;
            app.sg02_voltage_rating = v_rating;
            app.sg02_frequency_rating = f_rating;
            app.sg02_load = l;
            app.sg02_current = i;
            app.sg02_active = p;
            app.sg02_reactive = q;
            changeSg02Value(app);
        end

        function importdata_gen_sg03(app,s_rating, v_rating, f_rating, l, i, p, q)
            app.sg03_power_rating = s_rating/1000;
            app.sg03_voltage_rating = v_rating;
            app.sg03_frequency_rating = f_rating;
            app.sg03_load = l;
            app.sg03_current = i;
            app.sg03_active = p;
            app.sg03_reactive = q;
            changeSg03Value(app);
        end

        function importdata_gen_gt01(app,s_rating, v_rating, f_rating, l, i, p, q)
            app.gt01_power_rating = s_rating/1000;
            app.gt01_voltage_rating = v_rating;
            app.gt01_frequency_rating = f_rating;
            app.gt01_load = l;
            app.gt01_current = i;
            app.gt01_active = p;
            app.gt01_reactive = q;
            changeGt01Value(app);
        end
        
        
        %% transformer
        function importdata_trafo_sg01(app,s_rating,v_rating,f_rating, v_from,v_to, l, i, p, q)
            app.trasg01_power_rating = s_rating/1000;
            app.trasg01_voltage_rating = v_rating;
            app.trasg01_frequency_rating = f_rating;
            app.trasg01_voltage_from = v_from;
            app.trasg01_voltage_to = v_to;
            app.trasg01_load = l;
            app.trasg01_current = i;
            app.trasg01_active = p;
            app.trasg01_reactive = q;
        end

        function importdata_trafo_sg02(app,s_rating,v_rating,f_rating, v_from,v_to, l, i, p, q)
            app.trasg02_power_rating = s_rating/1000;
            app.trasg02_voltage_rating = v_rating;
            app.trasg02_frequency_rating = f_rating;
            app.trasg02_voltage_from = v_from;
            app.trasg02_voltage_to = v_to;
            app.trasg02_load = l;
            app.trasg02_current = i;
            app.trasg02_active = p;
            app.trasg02_reactive = q;
        end

        function importdata_trafo_sg03(app,s_rating,v_rating,f_rating, v_from,v_to, l, i, p, q)
            app.trasg03_power_rating = s_rating/1000;
            app.trasg03_voltage_rating = v_rating;
            app.trasg03_frequency_rating = f_rating;
            app.trasg03_voltage_from = v_from;
            app.trasg03_voltage_to = v_to;
            app.trasg03_load = l;
            app.trasg03_current = i;
            app.trasg03_active = p;
            app.trasg03_reactive = q;
        end

        function importdata_trafo_gt01(app,s_rating,v_rating,f_rating, v_from,v_to, l, i, p, q)
            app.tragt01_power_rating = s_rating/1000;
            app.tragt01_voltage_rating = v_rating;
            app.tragt01_frequency_rating = f_rating;
            app.tragt01_voltage_from = v_from;
            app.tragt01_voltage_to = v_to;
            app.tragt01_load = l;
            app.tragt01_current = i;
            app.tragt01_active = p;
            app.tragt01_reactive = q;
        end

        function importdata_trafo_ac01a(app,s_rating,v_rating,f_rating, v_from,v_to, l, i, p, q)
            app.trac01a_power_rating = s_rating/1000;
            app.trac01a_voltage_rating = v_rating;
            app.trac01a_frequency_rating = f_rating;
            app.trac01a_voltage_from = v_from;
            app.trac01a_voltage_to = v_to;
            app.trac01a_load = l;
            app.trac01a_current = i;
            app.trac01a_active = p;
            app.trac01a_reactive = q;
            changeTrAc01AValue(app);
        end

        function importdata_trafo_ac01b(app,s_rating,v_rating,f_rating, v_from,v_to, l, i, p, q)
            app.trac01b_power_rating = s_rating/1000;
            app.trac01b_voltage_rating = v_rating;
            app.trac01b_frequency_rating = f_rating;
            app.trac01b_voltage_from = v_from;
            app.trac01b_voltage_to = v_to;
            app.trac01b_load = l;
            app.trac01b_current = i;
            app.trac01b_active = p;
            app.trac01b_reactive = q;
            changeTrAc01BValue(app);
        end
        
        %% bus
        function importdata_bus_a02(app,v_rating,pg,pl,qg,ql, l, i, f)
            app.busa02_voltage_rating = v_rating;
            app.busa02_active_gen = pg;
            app.busa02_active_load = pl;
            app.busa02_reactive_gen = qg;
            app.busa02_reactive_load = ql;
            app.busa02_load = l;
            app.busa02_current = i;
            app.busa02_frequency = f;
        end

        function importdata_bus_a03(app,v_rating,pg,pl,qg,ql, l, i, f)
            app.busa03_voltage_rating = v_rating;
            app.busa03_active_gen = pg;
            app.busa03_active_load = pl;
            app.busa03_reactive_gen = qg;
            app.busa03_reactive_load = ql;
            app.busa03_load = l;
            app.busa03_current = i;
            app.busa03_frequency = f;
        end

        function importdata_bus_c01a(app,v_rating,pg,pl,qg,ql, l, i, f)
            app.busc01a_voltage_rating = v_rating;
            app.busc01a_active_gen = pg;
            app.busc01a_active_load = pl;
            app.busc01a_reactive_gen = qg;
            app.busc01a_reactive_load = ql;
            app.busc01a_load = l;
            app.busc01a_current = i;
            app.busc01a_frequency = f;
            changeC01AValue(app);
        end

        function importdata_bus_c01b(app,v_rating,pg,pl,qg,ql, l, i, f)
            app.busc01b_voltage_rating = v_rating;
            app.busc01b_active_gen = pg;
            app.busc01b_active_load = pl;
            app.busc01b_reactive_gen = qg;
            app.busc01b_reactive_load = ql;
            app.busc01b_load = l;
            app.busc01b_current = i;
            app.busc01b_frequency = f;
            changeC01BValue(app);
        end
        %% motor
        function importdata_motor_k1(app,p_rating, v_rating, f_rating, l, i, s, q)
            app.k1_power_rating = p_rating;
            app.k1_voltage_rating = v_rating;
            app.k1_frequency_rating = f_rating;
            app.k1_load = l;
            app.k1_current = i;
            app.k1_active = s;
            app.k1_reactive = q;
        end

        function importdata_motor_k3(app,p_rating, v_rating, f_rating, l, i, s, q)
            app.k3_power_rating = p_rating;
            app.k3_voltage_rating = v_rating;
            app.k3_frequency_rating = f_rating;
            app.k3_load = l;
            app.k3_current = i;
            app.k3_active = s;
            app.k3_reactive = q;
        end

        function importdata_motor_k2(app,p_rating, v_rating, f_rating, l, i, s, q)
            app.k2_power_rating = p_rating;
            app.k2_voltage_rating = v_rating;
            app.k2_frequency_rating = f_rating;
            app.k2_load = l;
            app.k2_current = i;
            app.k2_active = s;
            app.k2_reactive = q;
        end

        function importdata_motor_k4(app,p_rating, v_rating, f_rating, l, i, s, q)
            app.k4_power_rating = p_rating;
            app.k4_voltage_rating = v_rating;
            app.k4_frequency_rating = f_rating;
            app.k4_load = l;
            app.k4_current = i;
            app.k4_active = s;
            app.k4_reactive = q;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            default_variable(app)
        end

        % Button pushed function: StartStopSG01Button
        function StartStopSG01ButtonPushed(app, event)
            changeSg01Status(app);
            changeTrafoSg01Status(app);
            busA02Energize(app);
            busC01AEnergize(app);
            trafoAC01AEnergize(app);
            busC01Distributed(app);
            motorK1Run(app);
            motorK2Run(app);
            motorK3Run(app);
            motorK4Run(app);
        end

        % Button pushed function: StartStopSG02Button
        function StartStopSG02ButtonPushed(app, event)
            changeSg02Status(app);
            changeTrafoSg02Status(app);
            busA02Energize(app);
            busC01AEnergize(app);
            trafoAC01AEnergize(app);
            busC01Distributed(app);
            motorK1Run(app);
            motorK2Run(app);
            motorK3Run(app);
            motorK4Run(app);
        end

        % Button pushed function: StartStopSG03Button
        function StartStopSG03ButtonPushed(app, event)
            changeSg03Status(app);
            changeTrafoSg03Status(app);
            busA03Energize(app);
            busC01BEnergize(app)
            trafoAC01BEnergize(app);
            busC01Distributed(app);
            motorK1Run(app);
            motorK2Run(app);
            motorK3Run(app);
            motorK4Run(app);
        end

        % Button pushed function: StartStopGT01Button
        function StartStopGT01ButtonPushed(app, event)
            changeSg04Status(app);
            changeTrafoSg04Status(app);
            busA03Energize(app);
            busC01BEnergize(app);
            trafoAC01BEnergize(app);
            busC01Distributed(app);
            motorK1Run(app);
            motorK2Run(app);
            motorK3Run(app);
            motorK4Run(app);
        end

        % Button pushed function: OpenCloseSwitchSG01Button
        function OpenCloseSwitchSG01ButtonPushed(app, event)
            changeSwitchSg01Status(app);
            busA02Energize(app);
            busC01AEnergize(app);
            trafoAC01AEnergize(app);
            busC01Distributed(app);
            motorK1Run(app);
            motorK2Run(app);
            motorK3Run(app);
            motorK4Run(app);
        end

        % Button pushed function: OpenCloseSwitchSG02Button
        function OpenCloseSwitchSG02ButtonPushed(app, event)
            changeSwitchSg02Status(app);
            busA02Energize(app);
            busC01AEnergize(app);
            trafoAC01AEnergize(app);
            busC01Distributed(app);
           motorK1Run(app);
            motorK2Run(app);
            motorK3Run(app);
            motorK4Run(app);
        end

        % Button pushed function: OpenCloseSwitchSG03Button
        function OpenCloseSwitchSG03ButtonPushed(app, event)
            changeSwitchSg03Status(app);
            busA03Energize(app);
            busC01BEnergize(app);
            trafoAC01BEnergize(app);
            busC01Distributed(app);
            motorK1Run(app);
            motorK2Run(app);
            motorK3Run(app);
            motorK4Run(app);
        end

        % Button pushed function: OpenCloseSwitchGT01Button
        function OpenCloseSwitchGT01ButtonPushed(app, event)
            changeSwitchSg04Status(app);
            busA03Energize(app);
            busC01BEnergize(app);
            trafoAC01BEnergize(app);
            busC01Distributed(app);
            motorK1Run(app);
            motorK2Run(app);
            motorK3Run(app);
            motorK4Run(app);
        end

        % Button pushed function: OpenCloseSTAButton
        function OpenCloseSTAButtonPushed(app, event)
            changeSwitchSTAStatus(app);
            
            busC01AEnergize(app);
            trafoAC01AEnergize(app);
            busC01Distributed(app);
            motorK1Run(app);
            motorK2Run(app);
            motorK3Run(app);
            motorK4Run(app);
        end

        % Button pushed function: OpenCloseSTBButton
        function OpenCloseSTBButtonPushed(app, event)
            changeSwitchSTBStatus(app);
            busC01BEnergize(app);
            trafoAC01BEnergize(app);
            busC01Distributed(app);
            motorK1Run(app);
            motorK2Run(app);
            motorK3Run(app);
            motorK4Run(app);
        end

        % Button pushed function: OpenCloseSwAC01AButton
        function OpenCloseSwAC01AButtonPushed(app, event)
            changeSwitchAC01AStatus(app);
            busC01AEnergize(app);
            busC01Distributed(app);
            motorK1Run(app);
            motorK2Run(app);
            motorK3Run(app);
            motorK4Run(app);
        end

        % Button pushed function: OpenCloseSwAC01BButton
        function OpenCloseSwAC01BButtonPushed(app, event)
            changeSwitchAC01BStatus(app);
            busC01BEnergize(app);
            busC01Distributed(app);
            motorK1Run(app);
            motorK2Run(app);
            motorK3Run(app);
            motorK4Run(app);
        end

        % Button pushed function: OpenCloseSMAButton
        function OpenCloseSMAButtonPushed(app, event)
            changeSwitchSMAStatus(app);
            motorK3Run(app);
        end

        % Button pushed function: OpenCloseSMBButton
        function OpenCloseSMBButtonPushed(app, event)
            changeSwitchSMBStatus(app);
            motorK1Run(app);
        end

        % Button pushed function: OpenCloseSMCButton
        function OpenCloseSMCButtonPushed(app, event)
            changeSwitchSMCStatus(app);
            motorK2Run(app);
        end

        % Button pushed function: OpenCloseSMDButton
        function OpenCloseSMDButtonPushed(app, event)
            changeSwitchSMDStatus(app);
            motorK4Run(app);
        end

        % Button pushed function: OpenCloseSwitchC01Button
        function OpenCloseSwitchC01ButtonPushed(app, event)
            changeSwitchC01Status(app);
            busC01Distributed(app);
            motorK1Run(app);
            motorK2Run(app);
            motorK3Run(app);
            motorK4Run(app);
        end

        % Button pushed function: ForceConditionButton
        function ForceConditionButtonPushed(app, event)
            app.requirement = requirement(app);     %#ok 
            importdata(app.requirement,app.condition_1,app.condition_2,app.condition_3); %#ok 
        end

        % Button pushed function: StartPowerFlowButton
        function StartPowerFlowButtonPushed(app, event)
            runpowerflow(app);
        end

        % Image clicked function: Sg01Img
        function Sg01ImgImageClicked(app, event)
            psathmi = app;
            generator_id = 'SG01';
            generator_state = app.Sg01Status;
            s_rating=app.sg01_power_rating*1000;
            v_rating=app.sg01_voltage_rating;
            f_rating=app.sg01_frequency_rating;
            l=app.sg01_load;
            i=app.sg01_current;
            p=app.sg01_active;
            q=app.sg01_reactive;
            app.generator_instance =  generator(psathmi, generator_id, generator_state, s_rating, v_rating, f_rating ,l, i, p, q);
        end

        % Image clicked function: Sg02Img
        function Sg02ImgImageClicked(app, event)
            psathmi = app;
            generator_id = 'SG02';
            generator_state = app.Sg02Status;
            s_rating=app.sg02_power_rating*1000;
            v_rating=app.sg02_voltage_rating;
            f_rating=app.sg02_frequency_rating;
            l=app.sg02_load;
            i=app.sg02_current;
            p=app.sg02_active;
            q=app.sg02_reactive;
            app.generator_instance =  generator(psathmi, generator_id, generator_state, s_rating, v_rating, f_rating ,l, i, p, q);
        end

        % Image clicked function: Sg03Img
        function Sg03ImgImageClicked(app, event)
            psathmi = app;
            generator_id = 'SG03';
            generator_state = app.Sg03Status;
            s_rating=app.sg03_power_rating*1000;
            v_rating=app.sg03_voltage_rating;
            f_rating=app.sg03_frequency_rating;
            l=app.sg03_load;
            i=app.sg03_current;
            p=app.sg03_active;
            q=app.sg03_reactive;
            app.generator_instance =  generator(psathmi, generator_id, generator_state, s_rating, v_rating, f_rating ,l, i, p, q);
        end

        % Image clicked function: Sg04Img
        function Sg04ImgImageClicked(app, event)
            psathmi = app;
            generator_id = 'GT01';
            generator_state = app.Sg04Status;
            s_rating=app.gt01_power_rating*1000;
            v_rating=app.gt01_voltage_rating;
            f_rating=app.gt01_frequency_rating;
            l=app.gt01_load;
            i=app.gt01_current;
            p=app.gt01_active;
            q=app.gt01_reactive;
            app.generator_instance =  generator(psathmi, generator_id, generator_state, s_rating, v_rating, f_rating ,l, i, p, q);
        end

        % Image clicked function: TrafoSg01Img
        function TrafoSg01ImgImageClicked(app, event)
            psathmi = app;
            trafo_id = 'Trafo SG01';
            trafo_state = app.TrafoSg01Status;
            s_rating=app.trasg01_power_rating*1000;
            v_rating=app.trasg01_voltage_rating;
            f_rating=app.trasg01_frequency_rating;
            v_from=app.trasg01_voltage_from;
            v_to=app.trasg01_voltage_to;
            l=app.trasg01_load;
            i=app.trasg01_current;
            p=app.trasg01_active;
            q=app.trasg01_reactive;
            app.transformer_instance =  transformer(psathmi, trafo_id, trafo_state, s_rating, v_rating, f_rating, v_from, v_to, l, i, p, q);
        end

        % Image clicked function: TrafoSg02Img
        function TrafoSg02ImgImageClicked(app, event)
            psathmi = app;
            trafo_id = 'Trafo SG02';
            trafo_state = app.TrafoSg02Status;
            s_rating=app.trasg02_power_rating*1000;
            v_rating=app.trasg02_voltage_rating;
            f_rating=app.trasg02_frequency_rating;
            v_from=app.trasg02_voltage_from;
            v_to=app.trasg02_voltage_to;
            l=app.trasg02_load;
            i=app.trasg02_current;
            p=app.trasg02_active;
            q=app.trasg02_reactive;
            app.transformer_instance =  transformer(psathmi, trafo_id, trafo_state, s_rating, v_rating, f_rating, v_from, v_to, l, i, p, q);
        end

        % Image clicked function: TrafoSg03Img
        function TrafoSg03ImgImageClicked(app, event)
            psathmi = app;
            trafo_id = 'Trafo SG03';
            trafo_state = app.TrafoSg03Status;
            s_rating=app.trasg03_power_rating*1000;
            v_rating=app.trasg03_voltage_rating;
            f_rating=app.trasg03_frequency_rating;
            v_from=app.trasg03_voltage_from;
            v_to=app.trasg03_voltage_to;
            l=app.trasg03_load;
            i=app.trasg03_current;
            p=app.trasg03_active;
            q=app.trasg03_reactive;
            app.transformer_instance =  transformer(psathmi, trafo_id, trafo_state, s_rating, v_rating, f_rating, v_from, v_to, l, i, p, q);
        end

        % Image clicked function: TrafoSg04Img
        function TrafoSg04ImgImageClicked(app, event)
            psathmi = app;
            trafo_id = 'Trafo GT01';
            trafo_state = app.TrafoSg04Status;
            s_rating=app.tragt01_power_rating*1000;
            v_rating=app.tragt01_voltage_rating;
            f_rating=app.tragt01_frequency_rating;
            v_from=app.tragt01_voltage_from;
            v_to=app.tragt01_voltage_to;
            l=app.tragt01_load;
            i=app.tragt01_current;
            p=app.tragt01_active;
            q=app.tragt01_reactive;
            app.transformer_instance =  transformer(psathmi, trafo_id, trafo_state, s_rating, v_rating, f_rating, v_from, v_to, l, i, p, q);
        end

        % Image clicked function: TrafoAC01AImg
        function TrafoAC01AImgImageClicked(app, event)
            psathmi = app;
            trafo_id = 'Trafo AC01A';
            trafo_state = app.TrafoAC01AStatus;
            s_rating=app.trac01a_power_rating*1000;
            v_rating=app.trac01a_voltage_rating;
            f_rating=app.trac01a_frequency_rating;
            v_from=app.trac01a_voltage_from;
            v_to=app.trac01a_voltage_to;
            l=app.trac01a_load;
            i=app.trac01a_current;
            p=app.trac01a_active;
            q=app.trac01a_reactive;
            app.transformer_instance =  transformer(psathmi, trafo_id, trafo_state, s_rating, v_rating, f_rating, v_from, v_to, l, i, p, q);
        end

        % Image clicked function: TrafoAC01BImg
        function TrafoAC01BImgImageClicked(app, event)
            psathmi = app;
            trafo_id = 'Trafo AC01B';
            trafo_state = app.TrafoAC01BStatus;
            s_rating=app.trac01b_power_rating*1000;
            v_rating=app.trac01b_voltage_rating;
            f_rating=app.trac01b_frequency_rating;
            v_from=app.trac01b_voltage_from;
            v_to=app.trac01b_voltage_to;
            l=app.trac01b_load;
            i=app.trac01b_current;
            p=app.trac01b_active;
            q=app.trac01b_reactive;
            app.transformer_instance =  transformer(psathmi, trafo_id, trafo_state, s_rating, v_rating, f_rating, v_from, v_to, l, i, p, q);
        end

        % Image clicked function: k3Img
        function k3ImgImageClicked(app, event)
            psathmi = app;
            motor_id = 'K3-M';
            motor_state = app.K3Status;
            s_rating=app.k3_power_rating*1000;
            v_rating=app.k3_voltage_rating;
            f_rating=app.k3_frequency_rating;
            l=app.k3_load;
            i=app.k3_current;
            s=app.k3_active;
            q=app.k3_reactive;
            app.motor_instance =  motor(psathmi, motor_id, motor_state, s_rating, v_rating, f_rating ,l, i, s, q);
        end

        % Image clicked function: k1Img
        function k1ImgImageClicked(app, event)
        psathmi = app;
            motor_id = 'K1-M';
            motor_state = app.K1Status;
            s_rating=app.k1_power_rating*1000;
            v_rating=app.k1_voltage_rating;
            f_rating=app.k1_frequency_rating;
            l=app.k1_load;
            i=app.k1_current;
            p=app.k1_active;
            q=app.k1_reactive;
            app.motor_instance =  motor(psathmi, motor_id, motor_state, s_rating, v_rating, f_rating ,l, i, p, q);
        end

        % Image clicked function: k2Img
        function k2ImgImageClicked(app, event)
            psathmi = app;
            motor_id = 'K2-M';
            motor_state = app.K2Status;
            s_rating=app.k2_power_rating*1000;
            v_rating=app.k2_voltage_rating;
            f_rating=app.k2_frequency_rating;
            l=app.k2_load;
            i=app.k2_current;
            p=app.k2_active;
            q=app.k2_reactive;
            app.motor_instance =  motor(psathmi, motor_id, motor_state, s_rating, v_rating, f_rating ,l, i, p, q);
        end

        % Image clicked function: k4Img
        function k4ImgImageClicked(app, event)
            psathmi = app;
            motor_id = 'K4-M';
            motor_state = app.K4Status;
            s_rating=app.k4_power_rating*1000;
            v_rating=app.k4_voltage_rating;
            f_rating=app.k4_frequency_rating;
            l=app.k4_load;
            i=app.k4_current;
            p=app.k4_active;
            q=app.k4_reactive;
            app.motor_instance =  motor(psathmi, motor_id, motor_state, s_rating, v_rating, f_rating ,l, i, p, q);
        end

        % Image clicked function: BusA02Img
        function BusA02ImgImageClicked(app, event)
            psathmi = app;
            bus_id = '101-HSW-A02';
            bus_state = app.BusA02Status;
            v_rating=app.busa02_voltage_rating;
            pg=app.busa02_active_gen;
            pl=app.busa02_active_load;
            qg=app.busa02_reactive_gen;
            ql=app.busa02_reactive_load;
            l=app.busa02_load;
            i=app.busa02_current;
            f=app.busa02_frequency;
            app.bus_instance =  bus(psathmi, bus_id, bus_state, v_rating, pg, pl, qg, ql, l, i, f);
        end

        % Image clicked function: BusA03Img
        function BusA03ImgImageClicked(app, event)
            psathmi = app;
            bus_id = '101-HSW-A03';
            bus_state = app.BusA03Status;
            v_rating=app.busa03_voltage_rating;
            pg=app.busa03_active_gen;
            pl=app.busa03_active_load;
            qg=app.busa03_reactive_gen;
            ql=app.busa03_reactive_load;
            l=app.busa03_load;
            i=app.busa03_current;
            f=app.busa03_frequency;
            app.bus_instance =  bus(psathmi, bus_id, bus_state, v_rating, pg, pl, qg, ql, l, i, f);
        end

        % Image clicked function: BusC01AImg
        function BusC01AImgImageClicked(app, event)
            psathmi = app;
            bus_id = '101-HSW-C01A';
            bus_state = app.BusC01AStatus;
            v_rating=app.busc01a_voltage_rating;
            pg=app.busc01a_active_gen;
            pl=app.busc01a_active_load;
            qg=app.busc01a_reactive_gen;
            ql=app.busc01a_reactive_load;
            l=app.busc01a_load;
            i=app.busc01a_current;
            f=app.busc01a_frequency;
            app.bus_instance =  bus(psathmi, bus_id, bus_state, v_rating, pg, pl, qg, ql, l, i, f);
        end

        % Image clicked function: BusC01BImg
        function BusC01BImgImageClicked(app, event)
            psathmi = app;
            bus_id = '101-HSW-C01B';
            bus_state = app.BusC01BStatus;
            v_rating=app.busc01b_voltage_rating;
            pg=app.busc01b_active_gen;
            pl=app.busc01b_active_load;
            qg=app.busc01b_reactive_gen;
            ql=app.busc01b_reactive_load;
            l=app.busc01b_load;
            i=app.busc01b_current;
            f=app.busc01b_frequency;
            app.bus_instance =  bus(psathmi, bus_id, bus_state, v_rating, pg, pl, qg, ql, l, i, f);
        end

        % Button pushed function: LoadEvalButton
        function LoadEvalButtonPushed(app, event)
 
            ats_running(app);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if exist('Algeb','var')
                closepsat;
                clear all;
                close all;
            end
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = fullfile(pathToMLAPP, 'Assets', 'New Design.png');
            app.OpenCloseSwitchC01Button = uibutton(app.UIFigure, 'push');
            app.OpenCloseSwitchC01Button.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSwitchC01ButtonPushed, true);
            app.OpenCloseSwitchC01Button.Text = {'Open/Close'; 'Switch C01'};
            app.OpenCloseSMDButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSMDButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSMDButtonPushed, true);
            app.OpenCloseSMDButton.Text = {'Open/Close'; 'SMD'};
            app.OpenCloseSMCButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSMCButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSMCButtonPushed, true);
            app.OpenCloseSMCButton.Text = {'Open/Close'; 'SMC'};
            app.OpenCloseSMBButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSMBButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSMBButtonPushed, true);
            app.OpenCloseSMBButton.Text = {'Open/Close'; 'SMB'};
            app.OpenCloseSMAButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSMAButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSMAButtonPushed, true);
            app.OpenCloseSMAButton.Text = {'Open/Close'; 'SMA'};
            app.OpenCloseSwAC01AButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSwAC01AButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSwAC01AButtonPushed, true);
            app.OpenCloseSwAC01AButton.Text = {'Open/Close'; 'Sw. AC01A'};
            app.OpenCloseSwAC01BButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSwAC01BButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSwAC01BButtonPushed, true);
            app.OpenCloseSwAC01BButton.Text = {'Open/Close'; 'Sw. AC01B'};
            app.OpenCloseSTBButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSTBButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSTBButtonPushed, true);
            app.OpenCloseSTBButton.Text = {'Open/Close'; 'STB'};
            app.OpenCloseSTAButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSTAButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSTAButtonPushed, true);
            app.OpenCloseSTAButton.Text = {'Open/Close'; 'STA'};
            app.OpenCloseSwitchGT01Button = uibutton(app.UIFigure, 'push');
            app.OpenCloseSwitchGT01Button.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSwitchGT01ButtonPushed, true);
            app.OpenCloseSwitchGT01Button.Text = {'Open/Close'; 'Switch GT01'};
            app.OpenCloseSwitchSG03Button = uibutton(app.UIFigure, 'push');
            app.OpenCloseSwitchSG03Button.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSwitchSG03ButtonPushed, true);
            app.OpenCloseSwitchSG03Button.Text = {'Open/Close'; 'Switch SG03'};
            app.OpenCloseSwitchSG02Button = uibutton(app.UIFigure, 'push');
            app.OpenCloseSwitchSG02Button.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSwitchSG02ButtonPushed, true);
            app.OpenCloseSwitchSG02Button.Text = {'Open/Close'; 'Switch SG02'};
            app.OpenCloseSwitchSG01Button = uibutton(app.UIFigure, 'push');
            app.OpenCloseSwitchSG01Button.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSwitchSG01ButtonPushed, true);
            app.OpenCloseSwitchSG01Button.Text = {'Open/Close'; 'Switch SG01'};
            app.StartStopGT01Button = uibutton(app.UIFigure, 'push');
            app.StartStopGT01Button.ButtonPushedFcn = createCallbackFcn(app, @StartStopGT01ButtonPushed, true);
            app.StartStopGT01Button.Text = {'Start/Stop'; 'GT01'};
            app.StartStopSG03Button = uibutton(app.UIFigure, 'push');
            app.StartStopSG03Button.ButtonPushedFcn = createCallbackFcn(app, @StartStopSG03ButtonPushed, true);
            app.StartStopSG03Button.Text = {'Start/Stop'; 'SG03'};
            app.StartStopSG02Button = uibutton(app.UIFigure, 'push');
            app.StartStopSG02Button.ButtonPushedFcn = createCallbackFcn(app, @StartStopSG02ButtonPushed, true);
            app.StartStopSG02Button.Text = {'Start/Stop'; 'SG02'};
            app.StartStopSG01Button = uibutton(app.UIFigure, 'push');
            app.StartStopSG01Button.ButtonPushedFcn = createCallbackFcn(app, @StartStopSG01ButtonPushed, true);
            app.StartStopSG01Button.Text = {'Start/Stop'; 'SG01'};
            app.SwitchC01Img = uiimage(app.UIFigure);
            app.SwitchC01Img.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service) - 90.png');
            app.k4Img = uiimage(app.UIFigure);
            app.k4Img.ImageClickedFcn = createCallbackFcn(app, @k4ImgImageClicked, true);
            app.k4Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Motor - Comm. Loss.png');
            app.k3Img = uiimage(app.UIFigure);
            app.k3Img.ImageClickedFcn = createCallbackFcn(app, @k3ImgImageClicked, true);
            app.k3Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Motor - Comm. Loss.png');
            app.k2Img = uiimage(app.UIFigure);
            app.k2Img.ImageClickedFcn = createCallbackFcn(app, @k2ImgImageClicked, true);
            app.k2Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Motor - Comm. Loss.png');
            app.k1Img = uiimage(app.UIFigure);
            app.k1Img.ImageClickedFcn = createCallbackFcn(app, @k1ImgImageClicked, true);
            app.k1Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Motor - Comm. Loss.png');
            app.SwitchSMDImg = uiimage(app.UIFigure);
            app.SwitchSMDImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchSMCImg = uiimage(app.UIFigure);
            app.SwitchSMCImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchSMBImg = uiimage(app.UIFigure);
            app.SwitchSMBImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchSMAImg = uiimage(app.UIFigure);
            app.SwitchSMAImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.BusC01BImg = uiimage(app.UIFigure);
            app.BusC01BImg.ImageClickedFcn = createCallbackFcn(app, @BusC01BImgImageClicked, true);
            app.BusC01BImg.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Bus Standby.png');
            app.BusC01AImg = uiimage(app.UIFigure);
            app.BusC01AImg.ImageClickedFcn = createCallbackFcn(app, @BusC01AImgImageClicked, true);
            app.BusC01AImg.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Bus Standby.png');
            app.SwitchAC01BImg = uiimage(app.UIFigure);
            app.SwitchAC01BImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchAC01AImg = uiimage(app.UIFigure);
            app.SwitchAC01AImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.TrafoAC01BImg = uiimage(app.UIFigure);
            app.TrafoAC01BImg.ImageClickedFcn = createCallbackFcn(app, @TrafoAC01BImgImageClicked, true);
            app.TrafoAC01BImg.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Transformer Comm. Loss.png');
            app.TrafoAC01AImg = uiimage(app.UIFigure);
            app.TrafoAC01AImg.ImageClickedFcn = createCallbackFcn(app, @TrafoAC01AImgImageClicked, true);
            app.TrafoAC01AImg.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Transformer Comm. Loss.png');
            app.SwitchSTBImg = uiimage(app.UIFigure);
            app.SwitchSTBImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchSTAImg = uiimage(app.UIFigure);
            app.SwitchSTAImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.BusA03Img = uiimage(app.UIFigure);
            app.BusA03Img.ImageClickedFcn = createCallbackFcn(app, @BusA03ImgImageClicked, true);
            app.BusA03Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Bus Standby.png');
            app.BusA02Img = uiimage(app.UIFigure);
            app.BusA02Img.ImageClickedFcn = createCallbackFcn(app, @BusA02ImgImageClicked, true);
            app.BusA02Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Bus Standby.png');
            app.SwitchSg04Img = uiimage(app.UIFigure);
            app.SwitchSg04Img.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchSg03Img = uiimage(app.UIFigure);
            app.SwitchSg03Img.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchSg02Img = uiimage(app.UIFigure);
            app.SwitchSg02Img.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchSg01Img = uiimage(app.UIFigure);
            app.SwitchSg01Img.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.TrafoSg04Img = uiimage(app.UIFigure);
            app.TrafoSg04Img.ImageClickedFcn = createCallbackFcn(app, @TrafoSg04ImgImageClicked, true);
            app.TrafoSg04Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Transformer Comm. Loss.png');
            app.TrafoSg03Img = uiimage(app.UIFigure);
            app.TrafoSg03Img.ImageClickedFcn = createCallbackFcn(app, @TrafoSg03ImgImageClicked, true);
            app.TrafoSg03Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Transformer Comm. Loss.png');
            app.TrafoSg02Img = uiimage(app.UIFigure);
            app.TrafoSg02Img.ImageClickedFcn = createCallbackFcn(app, @TrafoSg02ImgImageClicked, true);
            app.TrafoSg02Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Transformer Comm. Loss.png');
            app.TrafoSg01Img = uiimage(app.UIFigure);
            app.TrafoSg01Img.ImageClickedFcn = createCallbackFcn(app, @TrafoSg01ImgImageClicked, true);
            app.TrafoSg01Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Transformer Comm. Loss.png');
            app.Sg04Img = uiimage(app.UIFigure);
            app.Sg04Img.ImageClickedFcn = createCallbackFcn(app, @Sg04ImgImageClicked, true);
            app.Sg04Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator - Comm. Loss.png');
            app.Sg03Img = uiimage(app.UIFigure);
            app.Sg03Img.ImageClickedFcn = createCallbackFcn(app, @Sg03ImgImageClicked, true);
            app.Sg03Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator - Comm. Loss.png');
            app.Sg02Img = uiimage(app.UIFigure);
            app.Sg02Img.ImageClickedFcn = createCallbackFcn(app, @Sg02ImgImageClicked, true);
            app.Sg02Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator - Comm. Loss.png');
            app.Sg01Img = uiimage(app.UIFigure);
            app.Sg01Img.ImageClickedFcn = createCallbackFcn(app, @Sg01ImgImageClicked, true);
            app.Sg01Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator - Comm. Loss.png');
            app.LoadEvalButton = uibutton(app.UIFigure, 'push');
            app.LoadEvalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEvalButtonPushed, true);
            app.LoadEvalButton.Text = {'Load'; 'Eval.'};
            app.StartPowerFlowButton = uibutton(app.UIFigure, 'push');
            app.StartPowerFlowButton.ButtonPushedFcn = createCallbackFcn(app, @StartPowerFlowButtonPushed, true);
            app.StartPowerFlowButton.Text = {'Start'; 'Power Flow'};
            app.OPCButton = uibutton(app.UIFigure, 'push');
            app.OPCButton.Text = 'OPC';
            app.SimulationOnLineButton = uibutton(app.UIFigure, 'push');
            app.SimulationOnLineButton.Text = {'Simulation/'; 'On-Line'};
            app.ForceConditionButton = uibutton(app.UIFigure, 'push');
            app.ForceConditionButton.ButtonPushedFcn = createCallbackFcn(app, @ForceConditionButtonPushed, true);
            app.ForceConditionButton.Text = {'Force'; 'Condition'};
            app.condition_3_label = uilabel(app.UIFigure);
            app.condition_3_label.Text = 'UNIT AUXILIARIES READY FOR START';
            app.condition_2_label = uilabel(app.UIFigure);
            app.condition_2_label.Text = 'UNIT BREAKING SYSTEM STATUS IS READY';
            app.condition_1_label = uilabel(app.UIFigure);
            app.condition_1_label.Text = 'GENERATOR BRAKES POSITION IS OFF';
            app.frequencySg01Unit = uilabel(app.UIFigure);
            app.frequencySg01Unit.Text = 'Hz';
            app.frequencySg01Value = uilabel(app.UIFigure);
            app.frequencySg01Value.Text = '******';
            app.voltageSg01Unit = uilabel(app.UIFigure);
            app.voltageSg01Unit.Text = 'kV';
            app.voltageSg01Value = uilabel(app.UIFigure);
            app.voltageSg01Value.Text = '******';
            app.currentSg01Unit = uilabel(app.UIFigure);
            app.currentSg01Unit.Text = 'A';
            app.currentSg01Value = uilabel(app.UIFigure);
            app.currentSg01Value.Text = '******';
            app.activeSg01Unit = uilabel(app.UIFigure);
            app.activeSg01Unit.Text = 'kVA';
            app.activeSg01Value = uilabel(app.UIFigure);
            app.activeSg01Value.Text = '******';
            app.reactiveSg01Unit = uilabel(app.UIFigure);
            app.reactiveSg01Unit.Text = 'kVAr';
            app.reactiveSg01Value = uilabel(app.UIFigure);
            app.reactiveSg01Value.Text = '******';
            app.powerSg01Unit = uilabel(app.UIFigure);
            app.powerSg01Unit.Text = 'kW';
            app.powerSg01Value = uilabel(app.UIFigure);
            app.powerSg01Value.Text = '******';
            app.powerSg02Value = uilabel(app.UIFigure);
            app.powerSg02Value.Text = '******';
            app.powerSg02Unit = uilabel(app.UIFigure);
            app.powerSg02Unit.Text = 'kW';
            app.reactiveSg02Value = uilabel(app.UIFigure);
            app.reactiveSg02Value.Text = '******';
            app.reactiveSg02Unit = uilabel(app.UIFigure);
            app.reactiveSg02Unit.Text = 'kVAr';
            app.activeSg02Value = uilabel(app.UIFigure);
            app.activeSg02Value.Text = '******';
            app.activeSg02Unit = uilabel(app.UIFigure);
            app.activeSg02Unit.Text = 'kVA';
            app.currentSg02Value = uilabel(app.UIFigure);
            app.currentSg02Value.Text = '******';
            app.currentSg02Unit = uilabel(app.UIFigure);
            app.currentSg02Unit.Text = 'A';
            app.voltageSg02Value = uilabel(app.UIFigure);
            app.voltageSg02Value.Text = '******';
            app.voltageSg02Unit = uilabel(app.UIFigure);
            app.voltageSg02Unit.Text = 'kV';
            app.frequencySg02Value = uilabel(app.UIFigure);
            app.frequencySg02Value.Text = '******';
            app.frequencySg02Unit = uilabel(app.UIFigure);
            app.frequencySg02Unit.Text = 'Hz';
            app.frequencySg03Unit = uilabel(app.UIFigure);
            app.frequencySg03Unit.Text = 'Hz';
            app.frequencySg03Value = uilabel(app.UIFigure);
            app.frequencySg03Value.Text = '******';
            app.voltageSg03Unit = uilabel(app.UIFigure);
            app.voltageSg03Unit.Text = 'kV';
            app.voltageSg03Value = uilabel(app.UIFigure);
            app.voltageSg03Value.Text = '******';
            app.currentSg03Unit = uilabel(app.UIFigure);
            app.currentSg03Unit.Text = 'A';
            app.currentSg03Value = uilabel(app.UIFigure);
            app.currentSg03Value.Text = '******';
            app.activeSg03Unit = uilabel(app.UIFigure);
            app.activeSg03Unit.Text = 'kVA';
            app.activeSg03Value = uilabel(app.UIFigure);
            app.activeSg03Value.Text = '******';
            app.reactiveSg03Unit = uilabel(app.UIFigure);
            app.reactiveSg03Unit.Text = 'kVAr';
            app.reactiveSg03Value = uilabel(app.UIFigure);
            app.reactiveSg03Value.Text = '******';
            app.powerSg03Unit = uilabel(app.UIFigure);
            app.powerSg03Unit.Text = 'kW';
            app.powerSg03Value = uilabel(app.UIFigure);
            app.powerSg03Value.Text = '******';
            app.frequencyGt01Unit = uilabel(app.UIFigure);
            app.frequencyGt01Unit.Text = 'Hz';
            app.powerGt01Value = uilabel(app.UIFigure);
            app.powerGt01Value.Text = '******';
            app.powerGt01Unit = uilabel(app.UIFigure);
            app.powerGt01Unit.Text = 'kW';
            app.reactiveGt01Value = uilabel(app.UIFigure);
            app.reactiveGt01Value.Text = '******';
            app.reactiveGt01Unit = uilabel(app.UIFigure);
            app.reactiveGt01Unit.Text = 'kVAr';
            app.activeGt01Value = uilabel(app.UIFigure);
            app.activeGt01Value.Text = '******';
            app.activeGt01Unit = uilabel(app.UIFigure);
            app.activeGt01Unit.Text = 'kVA';
            app.currentGt01Value = uilabel(app.UIFigure);
            app.currentGt01Value.Text = '******';
            app.currentGt01Unit = uilabel(app.UIFigure);
            app.currentGt01Unit.Text = 'A';
            app.voltageGt01Value = uilabel(app.UIFigure);
            app.voltageGt01Value.Text = '******';
            app.voltageGt01Unit = uilabel(app.UIFigure);
            app.voltageGt01Unit.Text = 'kV';
            app.frequencyGt01Value = uilabel(app.UIFigure);
            app.frequencyGt01Value.Text = '******';
            app.voltagetTrAc01AUnit = uilabel(app.UIFigure);
            app.voltagetTrAc01AUnit.Text = 'kV';
            app.voltageTrAc01AValue = uilabel(app.UIFigure);
            app.voltageTrAc01AValue.Text = '******';
            app.currentTrAc01AUnit = uilabel(app.UIFigure);
            app.currentTrAc01AUnit.Text = 'A';
            app.currentTrAc01AValue = uilabel(app.UIFigure);
            app.currentTrAc01AValue.Text = '******';
            app.reactiveTrAc01AUnit = uilabel(app.UIFigure);
            app.reactiveTrAc01AUnit.Text = 'kVAr';
            app.reactiveTrAc01AValue = uilabel(app.UIFigure);
            app.reactiveTrAc01AValue.Text = '******';
            app.powerTrAc01AUnit = uilabel(app.UIFigure);
            app.powerTrAc01AUnit.Text = 'kW';
            app.powerTrAc01AValue = uilabel(app.UIFigure);
            app.powerTrAc01AValue.Text = '******';
            app.voltagetTrAc01BUnit = uilabel(app.UIFigure);
            app.voltagetTrAc01BUnit.Text = 'kV';
            app.voltageTrAc01BValue = uilabel(app.UIFigure);
            app.voltageTrAc01BValue.Text = '******';
            app.currentTrAc01BUnit = uilabel(app.UIFigure);
            app.currentTrAc01BUnit.Text = 'A';
            app.currentTrAc01BValue = uilabel(app.UIFigure);
            app.currentTrAc01BValue.Text = '******';
            app.reactiveTrAc01BUnit = uilabel(app.UIFigure);
            app.reactiveTrAc01BUnit.Text = 'kVAr';
            app.reactiveTrAc01BValue = uilabel(app.UIFigure);
            app.reactiveTrAc01BValue.Text = '******';
            app.powerTrAc01BUnit = uilabel(app.UIFigure);
            app.powerTrAc01BUnit.Text = 'kW';
            app.powerTrAc01BValue = uilabel(app.UIFigure);
            app.powerTrAc01BValue.Text = '******';
            app.frequencyC01AUnit = uilabel(app.UIFigure);
            app.frequencyC01AUnit.Text = 'Hz';
            app.frequencyC01AValue = uilabel(app.UIFigure);
            app.frequencyC01AValue.Text = '******';
            app.voltageC01AUnit = uilabel(app.UIFigure);
            app.voltageC01AUnit.Text = 'kV';
            app.voltageC01AValue = uilabel(app.UIFigure);
            app.voltageC01AValue.Text = '******';
            app.frequencyC01BUnit = uilabel(app.UIFigure);
            app.frequencyC01BUnit.Text = 'Hz';
            app.frequencyC01BValue = uilabel(app.UIFigure);
            app.frequencyC01BValue.Text = '******';
            app.voltageC01BUnit = uilabel(app.UIFigure);
            app.voltageC01BUnit.Text = 'kV';
            app.voltageC01BValue = uilabel(app.UIFigure);
            app.voltageC01BValue.Text = '******';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Assets', 'logo.png');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Assets', 'top_sample.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Assets', 'bottom_sample.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = psathmi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end