classdef requirement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PrestartRequirementUIFigure  matlab.ui.Figure
        title                        matlab.ui.control.Label
        ButtonGroup                  matlab.ui.container.ButtonGroup
        condition_1_checkbox         matlab.ui.control.CheckBox
        condition_2_checkbox         matlab.ui.control.CheckBox
        condition_3_checkbox         matlab.ui.control.CheckBox
        condition_2_button           matlab.ui.control.Button
        condition_3_button           matlab.ui.control.Button
        condition_1_button           matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingapp
    end
    
    properties (Access = public)
        condition_1 
        condition_2
        condition_3
    end
    
    methods (Access = public)       
        function importdata(app,condition_1,condition_2,condition_3)
            app.condition_1 = condition_1;
            app.condition_2 = condition_2;
            app.condition_3 = condition_3;
            if condition_1 == 1
                app.condition_1_button.BackgroundColor = 'g';
                app.condition_1_checkbox.Value = 1;
            else
                app.condition_1_button.BackgroundColor = 'r';
                app.condition_1_checkbox.Value = 0;
            end

            if condition_2 == 1
                app.condition_2_button.BackgroundColor = 'g';
                app.condition_2_checkbox.Value = 1;
            else
                app.condition_2_button.BackgroundColor = 'r';
                app.condition_2_checkbox.Value = 0;
            end

            if condition_3 == 1
                app.condition_3_button.BackgroundColor = 'g';
                app.condition_3_checkbox.Value = 1;
            else
                app.condition_3_button.BackgroundColor = 'r';
                app.condition_3_checkbox.Value = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, guisimulation)
            app.callingapp=guisimulation;
        end

        % Callback function: condition_1_button, condition_1_checkbox
        function condition_1_buttonPushed(app, event)
            if app.condition_1_checkbox.Value == 1
                app.condition_1_checkbox.Value = 0;
                app.condition_1_button.BackgroundColor = 'r';
                app.condition_1 = 0;
            else
                app.condition_1_checkbox.Value = 1;
                app.condition_1_button.BackgroundColor = 'g';
                app.condition_1 = 1;
            end
            importdata(app.callingapp,app.condition_1,app.condition_2,app.condition_3)
            
        end

        % Callback function: condition_2_button, condition_2_checkbox
        function condition_2_buttonButtonPushed(app, event)
            if app.condition_2_checkbox.Value == 1
                app.condition_2_checkbox.Value = 0;
                app.condition_2_button.BackgroundColor = 'r';
                app.condition_2 = 0;
            else
                app.condition_2_checkbox.Value = true;
                app.condition_2_button.BackgroundColor = 'g';
                app.condition_2 = 1;
            end
            importdata(app.callingapp,app.condition_1,app.condition_2,app.condition_3)
        end

        % Button pushed function: condition_3_button
        function condition_3_buttonButtonPushed(app, event)
            if app.condition_3_checkbox.Value == 1
                app.condition_3_checkbox.Value = 0;
                app.condition_3_button.BackgroundColor = 'r';
                app.condition_3 = 0;
            else
                app.condition_3_checkbox.Value = 1;
                app.condition_3_button.BackgroundColor = 'g';
                app.condition_3 = 1;
            end
            importdata(app.callingapp,app.condition_1,app.condition_2,app.condition_3)
        end

        % Key press function: PrestartRequirementUIFigure
        function PrestartRequirementUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'escape')
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PrestartRequirementUIFigure = uifigure('Visible', 'off');
            app.PrestartRequirementUIFigure.Name = 'Prestart Requirement';
            app.PrestartRequirementUIFigure.KeyPressFcn = createCallbackFcn(app, @PrestartRequirementUIFigureKeyPress, true);
            app.ButtonGroup = uibuttongroup(app.PrestartRequirementUIFigure);
            app.condition_1_button = uibutton(app.ButtonGroup, 'push');
            app.condition_1_button.ButtonPushedFcn = createCallbackFcn(app, @condition_1_buttonPushed, true);
            app.condition_1_button.Text = 'GENERATOR BREAKS POSITION IS READY';
            app.condition_3_button = uibutton(app.ButtonGroup, 'push');
            app.condition_3_button.ButtonPushedFcn = createCallbackFcn(app, @condition_3_buttonButtonPushed, true);
            app.condition_3_button.Text = 'UNIT AUXILIARIES READY FOR START';
            app.condition_2_button = uibutton(app.ButtonGroup, 'push');
            app.condition_2_button.ButtonPushedFcn = createCallbackFcn(app, @condition_2_buttonButtonPushed, true);
            app.condition_2_button.Text = 'UNIT BREAKING SYSTEM STATUS IS READY';
            app.condition_3_checkbox = uicheckbox(app.ButtonGroup);
            app.condition_3_checkbox.Text = 'Ready';
            app.condition_2_checkbox = uicheckbox(app.ButtonGroup);
            app.condition_2_checkbox.ValueChangedFcn = createCallbackFcn(app, @condition_2_buttonButtonPushed, true);
            app.condition_2_checkbox.Text = 'Ready';
            app.condition_1_checkbox = uicheckbox(app.ButtonGroup);
            app.condition_1_checkbox.ValueChangedFcn = createCallbackFcn(app, @condition_1_buttonPushed, true);
            app.condition_1_checkbox.Text = 'Ready';
            app.title = uilabel(app.PrestartRequirementUIFigure);
            app.title.Text = ' PRESTART CONDITION FOR OPERATION';
            % Show the figure after all components are created
            app.PrestartRequirementUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = requirement(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PrestartRequirementUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PrestartRequirementUIFigure)
        end
    end
end