classdef transformer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TransformerUIFigure            matlab.ui.Figure
        SaveButton                     matlab.ui.control.Button
        title                          matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        v_to_trafo_EditField           matlab.ui.control.NumericEditField
        v_to_unit_trafo_EditField      matlab.ui.control.EditField
        v_from_trafo_EditField         matlab.ui.control.NumericEditField
        v_from_unit_trafo_EditField    matlab.ui.control.EditField
        f_trafo_rating_EditField       matlab.ui.control.NumericEditField
        f_unit_trafo_rating_EditField  matlab.ui.control.EditField
        p_trafo_EditField              matlab.ui.control.NumericEditField
        p_unit_trafo_EditField         matlab.ui.control.EditField
        l_trafo_EditField              matlab.ui.control.NumericEditField
        l_unit_trafo_EditField         matlab.ui.control.EditField
        i_trafo_EditField              matlab.ui.control.NumericEditField
        i_unit_trafo_EditField         matlab.ui.control.EditField
        v_trafo_rating_EditField       matlab.ui.control.NumericEditField
        v_unit_trafo_rating_EditField  matlab.ui.control.EditField
        q_trafo_EditField              matlab.ui.control.NumericEditField
        q_unit_trafo_EditField         matlab.ui.control.EditField
        s_trafo_rating_EditField       matlab.ui.control.NumericEditField
        s_unit_trafo_rating_EditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        callingapp % Description
        trafo_id;
        trafo_state;
        s_trafo_rating;
        v_trafo_rating;
        f_trafo_rating;
        v_from_trafo;
        v_to_trafo;
        l_trafo;
        i_trafo;
        p_trafo;
        q_trafo;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, psathmi, trafo_id, trafo_state, s_rating, v_rating, f_rating, v_from, v_to, l, i, p, q)
            %instance
            app.callingapp = psathmi;
            app.trafo_id = num2str(trafo_id);
            app.title.Text = strcat(app.title.Text,app.trafo_id);
            
            %variable
            app.s_trafo_rating = s_rating;
            app.v_trafo_rating = v_rating;
            app.f_trafo_rating = f_rating;
            app.v_from_trafo = v_from;
            app.v_to_trafo = v_to;
            app.l_trafo = l;
            app.i_trafo = i;
            app.p_trafo = p;
            app.q_trafo = q;
            

            %display value
            app.s_trafo_rating_EditField.Value = s_rating;
            app.v_trafo_rating_EditField.Value = v_rating;
            app.f_trafo_rating_EditField.Value = f_rating;
            app.v_from_trafo_EditField.Value = v_from;
            app.v_to_trafo_EditField.Value = v_to;
            app.i_trafo_EditField.Value = i;
            app.l_trafo_EditField.Value = l;
            app.p_trafo_EditField.Value = p;
            app.q_trafo_EditField.Value = q;
        end

        % Key press function: TransformerUIFigure
        function TransformerUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'escape')
                delete(app);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            s_rating = app.s_trafo_rating_EditField.Value;
            v_rating = app.v_trafo_rating_EditField.Value;
            f_rating = app.f_trafo_rating_EditField.Value;
            v_from = app.v_from_trafo_EditField.Value;
            v_to = app.v_to_trafo_EditField.Value;
            i = app.i_trafo_EditField.Value;
            l = app.l_trafo_EditField.Value;
            p = app.p_trafo_EditField.Value;
            q = app.q_trafo_EditField.Value;
            if strcmp(app.trafo_id,'Trafo SG01')
                importdata_trafo_sg01(app.callingapp,s_rating,v_rating,f_rating, v_from,v_to, l, i, p, q)
            elseif strcmp(app.trafo_id,'Trafo SG02')
                importdata_trafo_sg02(app.callingapp,s_rating,v_rating,f_rating, v_from,v_to, l, i, p, q)
            elseif strcmp(app.trafo_id,'Trafo SG03')
                importdata_trafo_sg03(app.callingapp,s_rating,v_rating,f_rating, v_from,v_to, l, i, p, q)
            elseif strcmp(app.trafo_id,'Trafo GT01')
                importdata_trafo_gt01(app.callingapp,s_rating,v_rating,f_rating, v_from,v_to, l, i, p, q)
            elseif strcmp(app.trafo_id,'Trafo AC01A')
                importdata_trafo_ac01a(app.callingapp,s_rating,v_rating,f_rating, v_from,v_to, l, i, p, q)
            elseif strcmp(app.trafo_id,'Trafo AC01B')
                importdata_trafo_ac01b(app.callingapp,s_rating,v_rating,f_rating, v_from,v_to, l, i, p, q)
            else
                
            end

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TransformerUIFigure = uifigure('Visible', 'off');
            app.TransformerUIFigure.Name = 'Transformer';
            app.TransformerUIFigure.KeyPressFcn = createCallbackFcn(app, @TransformerUIFigureKeyPress, true);
            app.ButtonGroup = uibuttongroup(app.TransformerUIFigure);
            app.s_unit_trafo_rating_EditField = uieditfield(app.ButtonGroup, 'text');
            app.s_unit_trafo_rating_EditField.Value = 'kVA';
            app.s_trafo_rating_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.q_unit_trafo_EditField = uieditfield(app.ButtonGroup, 'text');
            app.q_unit_trafo_EditField.Value = 'kVar';
            app.q_trafo_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.v_unit_trafo_rating_EditField = uieditfield(app.ButtonGroup, 'text');
            app.v_unit_trafo_rating_EditField.Value = 'kV';
            app.v_trafo_rating_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.i_unit_trafo_EditField = uieditfield(app.ButtonGroup, 'text');
            app.i_unit_trafo_EditField.Value = 'A';
            app.i_trafo_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.l_unit_trafo_EditField = uieditfield(app.ButtonGroup, 'text');
            app.l_unit_trafo_EditField.Value = '%';
            app.l_trafo_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.p_unit_trafo_EditField = uieditfield(app.ButtonGroup, 'text');
            app.p_unit_trafo_EditField.Value = 'kW';
            app.p_trafo_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.f_unit_trafo_rating_EditField = uieditfield(app.ButtonGroup, 'text');
            app.f_unit_trafo_rating_EditField.Value = 'Hz';
            app.f_trafo_rating_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.v_from_unit_trafo_EditField = uieditfield(app.ButtonGroup, 'text');
            app.v_from_unit_trafo_EditField.Value = 'kV From';
            app.v_from_trafo_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.v_to_unit_trafo_EditField = uieditfield(app.ButtonGroup, 'text');
            app.v_to_unit_trafo_EditField.Value = 'kV To';
            app.v_to_trafo_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.title = uilabel(app.TransformerUIFigure);
            app.title.Text = 'TRANSFORMER UNIT # ';
            app.SaveButton = uibutton(app.TransformerUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.TransformerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transformer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TransformerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TransformerUIFigure)
        end
    end
end